classdef BioMechAnalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        uitoolbar1                    matlab.ui.container.Toolbar
        error_box_tab                 matlab.ui.container.Panel
        No_button_error               matlab.ui.control.StateButton
        Yes_button_error              matlab.ui.control.StateButton
        OK_button_error               matlab.ui.control.StateButton
        text_error_box                matlab.ui.control.TextArea
        model_param_tab               matlab.ui.container.Panel
        param_14                      matlab.ui.control.EditField
        Parameter14Label              matlab.ui.control.Label
        param_3                       matlab.ui.control.EditField
        Parameter3Label               matlab.ui.control.Label
        param_11                      matlab.ui.control.EditField
        Parameter11Label              matlab.ui.control.Label
        param_10                      matlab.ui.control.EditField
        Parameter10Label              matlab.ui.control.Label
        param_9                       matlab.ui.control.EditField
        Parameter9Label               matlab.ui.control.Label
        param_8                       matlab.ui.control.EditField
        Parameter8Label               matlab.ui.control.Label
        param_7                       matlab.ui.control.EditField
        Parameter7Label               matlab.ui.control.Label
        param_13                      matlab.ui.control.EditField
        Parameter13Label              matlab.ui.control.Label
        param_12                      matlab.ui.control.EditField
        Parameter12Label              matlab.ui.control.Label
        param_6                       matlab.ui.control.EditField
        Parameter6Label               matlab.ui.control.Label
        param_5                       matlab.ui.control.EditField
        Parameter5Label               matlab.ui.control.Label
        param_4                       matlab.ui.control.EditField
        Parameter4Label               matlab.ui.control.Label
        param_2                       matlab.ui.control.EditField
        Parameter2Label               matlab.ui.control.Label
        param_1                       matlab.ui.control.EditField
        Parameter1Label               matlab.ui.control.Label
        OKButton                      matlab.ui.control.StateButton
        mod_settings_tab              matlab.ui.container.Panel
        active_model                  matlab.ui.control.DropDown
        MemoryfunctionLabel_3         matlab.ui.control.Label
        EnterButton                   matlab.ui.control.StateButton
        ref_conf_menu                 matlab.ui.control.DropDown
        ReferenceconfigurationLabel   matlab.ui.control.Label
        input_data_menu               matlab.ui.control.DropDown
        MemoryfunctionLabel_2         matlab.ui.control.Label
        memory_fun_menu               matlab.ui.control.DropDown
        MemoryfunctionLabel           matlab.ui.control.Label
        SEF_menu                      matlab.ui.control.DropDown
        StrainenergyfunctionLabel     matlab.ui.control.Label
        simulate_tab                  matlab.ui.container.Panel
        VSMCcontractionSwitch         matlab.ui.control.Switch
        VSMCcontractionSwitchLabel    matlab.ui.control.Label
        CompareSwitch                 matlab.ui.control.Switch
        CompareSwitchLabel            matlab.ui.control.Label
        graphChoice                   matlab.ui.control.DropDown
        SaveButton                    matlab.ui.control.Button
        Load_data_Button_simulate     matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        HoldsimulationButton          matlab.ui.control.Button
        RunsimulationButton           matlab.ui.control.Button
        waveform_type_menu            matlab.ui.control.DropDown
        WaveformtypeDropDownLabel     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Axial_stretch_sim             matlab.ui.control.EditField
        AxialstretchEditField_2Label  matlab.ui.control.Label
        Hz_knob                       matlab.ui.control.Knob
        LoadingfrequencyLabel         matlab.ui.control.Label
        mmHgLabel                     matlab.ui.control.Label
        DBP_sim                       matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        SBP_sim                       matlab.ui.control.EditField
        PressurerangeEditFieldLabel   matlab.ui.control.Label
        ofinvivoaxialstretchLabel     matlab.ui.control.Label
        Hz_value                      matlab.ui.control.EditField
        HzLabel                       matlab.ui.control.Label
        axes3_simulate_tab            matlab.ui.control.UIAxes
        axes2_simulate_tab            matlab.ui.control.UIAxes
        axes1_simulate_tab            matlab.ui.control.UIAxes
        simulate_button               matlab.ui.control.Button
        text_mmHg_slider              matlab.ui.control.Label
        data_analysis_tab_button      matlab.ui.control.Button
        Const_mod_tab_button          matlab.ui.control.Button
        dat_proc_tab                  matlab.ui.container.Panel
        text59_2                      matlab.ui.control.Label
        contraction_menu              matlab.ui.control.DropDown
        data_proc_dial                matlab.ui.control.SemicircularGauge
        LoadeddataLabel               matlab.ui.control.Label
        axial_stretch_menu            matlab.ui.control.DropDown
        protocol_menu                 matlab.ui.control.DropDown
        text60                        matlab.ui.control.Label
        static_dyna_menu_data_proc    matlab.ui.control.DropDown
        text59                        matlab.ui.control.Label
        mouse_ID_text                 matlab.ui.control.EditField
        text58                        matlab.ui.control.Label
        text57                        matlab.ui.control.Label
        text56                        matlab.ui.control.Label
        text55                        matlab.ui.control.Label
        text54                        matlab.ui.control.Label
        unloaded_lz                   matlab.ui.control.EditField
        text53                        matlab.ui.control.Label
        unloaded_Ri                   matlab.ui.control.EditField
        unloaded_Ro                   matlab.ui.control.EditField
        text52                        matlab.ui.control.Label
        text51                        matlab.ui.control.Label
        dynamic_menu                  matlab.ui.control.DropDown
        load_data_dat_proc            matlab.ui.control.Button
        axes4_dat_proc_tab            matlab.ui.control.UIAxes
        axes3_dat_proc_tab            matlab.ui.control.UIAxes
        axes2_dat_proc_tab            matlab.ui.control.UIAxes
        axes1_dat_proc_tab            matlab.ui.control.UIAxes
        dat_proc_tab_button           matlab.ui.control.Button
        Measurements_tab              matlab.ui.container.Panel
        graph_choice_mod_tab_2        matlab.ui.control.DropDown
        ref_conf                      matlab.ui.control.Label
        text49                        matlab.ui.control.Label
        N_param_dynamic               matlab.ui.control.Label
        text47                        matlab.ui.control.Label
        text46                        matlab.ui.control.Label
        N_param_constrained           matlab.ui.control.Label
        text44                        matlab.ui.control.Label
        N_param_static                matlab.ui.control.Label
        text42                        matlab.ui.control.Label
        memory_fun                    matlab.ui.control.Label
        random_text_44                matlab.ui.control.Label
        SEF                           matlab.ui.control.Label
        random_text2                  matlab.ui.control.Label
        in_vivo_axial_stretch_mod     matlab.ui.control.Label
        text36                        matlab.ui.control.Label
        in_vivo_axial_stretch_exp     matlab.ui.control.Label
        text34                        matlab.ui.control.Label
        dynamic_R2_value              matlab.ui.control.Label
        text32                        matlab.ui.control.Label
        static_R2_value               matlab.ui.control.Label
        text_not_cahnged              matlab.ui.control.Label
        graph_choice_mod_tab          matlab.ui.control.DropDown
        status_txt                    matlab.ui.control.Label
        text27                        matlab.ui.control.Label
        Load_data_mod_tab             matlab.ui.control.Button
        axes2_mod_tab                 matlab.ui.control.UIAxes
        axes1_mod_tab                 matlab.ui.control.UIAxes
        text26                        matlab.ui.control.Label
        clear                         matlab.ui.control.Button
        save                          matlab.ui.control.Button
        Reset                         matlab.ui.control.Button
        text25                        matlab.ui.control.Label
        DBP_value                     matlab.ui.control.EditField
        text24                        matlab.ui.control.Label
        text23                        matlab.ui.control.Label
        SBP_value                     matlab.ui.control.EditField
        text22                        matlab.ui.control.Label
        text21                        matlab.ui.control.Label
        text20                        matlab.ui.control.Label
        graph_choice                  matlab.ui.control.DropDown
        load_data_button              matlab.ui.control.Button
        calculate                     matlab.ui.control.Button
        text18                        matlab.ui.control.Label
        text17                        matlab.ui.control.Label
        text16                        matlab.ui.control.Label
        distensibility_value          matlab.ui.control.Label
        PWV_value                     matlab.ui.control.Label
        text15                        matlab.ui.control.Label
        text14                        matlab.ui.control.Label
        text13                        matlab.ui.control.Label
        text12                        matlab.ui.control.Label
        Czzzz_value                   matlab.ui.control.Label
        Ctttt_value                   matlab.ui.control.Label
        text11                        matlab.ui.control.Label
        text10                        matlab.ui.control.Label
        text7                         matlab.ui.control.Label
        text6                         matlab.ui.control.Label
        sigmazz_value                 matlab.ui.control.Label
        sigmatt_value                 matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        pressure_value                matlab.ui.control.EditField
        static_pressure               matlab.ui.control.Label
        pressure_slider               matlab.ui.control.Slider
        text21_3                      matlab.ui.control.Label
        text13_3                      matlab.ui.control.Label
        text12_3                      matlab.ui.control.Label
        axial_coll_LB                 matlab.ui.control.Label
        circ_coll_LB                  matlab.ui.control.Label
        text11_3                      matlab.ui.control.Label
        text10_3                      matlab.ui.control.Label
        text21_2                      matlab.ui.control.Label
        text13_2                      matlab.ui.control.Label
        text12_2                      matlab.ui.control.Label
        axial_el_LB                   matlab.ui.control.Label
        circ_el_LB                    matlab.ui.control.Label
        text11_2                      matlab.ui.control.Label
        text10_2                      matlab.ui.control.Label
        axes2                         matlab.ui.control.UIAxes
        axes1                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BioMechAnalysis_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to BioMechAnalysis (see VARARGIN)
            
            % Choose default command line output for BioMechAnalysis
%             handles.output = hObject;
            
            % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: dat_proc_tab_button
        function dataProcessingTab(app, event)
            set(app.Measurements_tab,'visible','on');
            set(app.dat_proc_tab,'visible','on');
            set(app.simulate_tab,'visible','off');
            set(app.static_dyna_menu_data_proc,'visible','on');
            set(app.text_mmHg_slider,'visible','off');
            set(app.data_analysis_tab_button,'FontWeight','normal');
            set(app.Const_mod_tab_button,'FontWeight','normal');
            set(app.dat_proc_tab_button,'FontWeight','bold');
            set(app.simulate_button,'FontWeight','normal');

            set(app.data_analysis_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.Const_mod_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.dat_proc_tab_button,'BackgroundColor','0.94,0.94,0.94');
            set(app.simulate_button,'BackgroundColor','0.86,0.86,0.86');
        end

        % Button pushed function: load_data_dat_proc
        function loadDataDataProcessingTab(app, event)
            
            app.load_data_dat_proc.BackgroundColor = [0.86,0.86,0.86];

            axes(app.axes1_dat_proc_tab);
            cla reset;
            axes(app.axes2_dat_proc_tab);
            cla reset;
            axes(app.axes3_dat_proc_tab);
            cla reset;
            axes(app.axes4_dat_proc_tab);
            cla reset;

            if(strcmp(app.protocol_menu.Value,'Custom'))
                [file_protocol,dir_file_protocol] = uigetfile('*.mat', 'Select a protocol file');
                app.load_data_dat_proc.UserData.file_protocol = fullfile(dir_file_protocol, file_protocol);
            end
            
            if(~strcmp(app.unloaded_Ro.Value,'....') && ~strcmp(app.unloaded_Ri.Value,'....') &&...
                ~strcmp(app.unloaded_lz.Value,'....') && ~strcmp(app.mouse_ID_text.Value,'....') &&...
                ~isnan(str2double(app.unloaded_Ro.Value)) && ~isnan(str2double(app.unloaded_Ri.Value)) &&...
                ~isnan(str2double(app.unloaded_lz.Value)))
                app.data_proc_dial.Value = 0;
                [passive,directoryName] = processQuasiStaticExperiments(app);

                if(strcmp(app.dynamic_menu.Value,'On'))
                    passive = processDynamicExperiments(passive, app, directoryName);
                end

                if(strcmp(app.contraction_menu.Value,'On'))
                    experimental_data = processContractionData(passive, app, event, directoryName);
                else
                    experimental_data.passive = passive;
                end
                
                % SAVE "passive" TO FILE USING MOUSE ID
                if(prod(directoryName~=0))
                    mouse_ID = app.mouse_ID_text.Value; % identification number that will appear as filename in .dat files
                    save(sprintf('mouse_%s_data.mat',mouse_ID), 'experimental_data');
                    app.load_data_dat_proc.UserData.passive = passive;
                    
                    app = appErrorDialogBox(app,'off','off','on','DONE',...
                            ['The experimental data of ' mouse_ID ' has been sucessfully processed and saved as mouse_' mouse_ID '_data.mat.']);
                end
            else
                app = appErrorDialogBox(app,'off','off','on','ERROR: missing data',...
                            'Please insert unloaded dimensions and then re-click on load data.');
            end

            app.load_data_dat_proc.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: static_dyna_menu_data_proc
        function graphChoiceDataProcessing(app, event)
            colour_scheme = {'r','b','g','m','k','y'}; % colour scheme for plots

            cla(app.axes1_dat_proc_tab);
            cla(app.axes2_dat_proc_tab);
            cla(app.axes3_dat_proc_tab);
            cla(app.axes4_dat_proc_tab);
            hold (app.axes1_dat_proc_tab,'on')
            hold (app.axes2_dat_proc_tab,'on')
            hold (app.axes3_dat_proc_tab,'on')
            hold (app.axes4_dat_proc_tab,'on')
            
            if(isfield(app.load_data_dat_proc.UserData,'passive') || isfield(app.load_data_dat_proc.UserData,'experimental_data'))
                
                if(isfield(app.load_data_dat_proc.UserData,'passive'))
                    passive_data = app.load_data_dat_proc.UserData.passive;
                else
                    passive_data = app.load_data_dat_proc.UserData.experimental_data.passive;
                end

                if(strcmp(app.static_dyna_menu_data_proc.Value,'Dynamic (physiological)') && (~isfield(passive_data,'dynamic_data')))
                    app = appErrorDialogBox(app,'off','off','on','ERROR: Missing dynamic data',...
                            'Dynamic data not found in the structure.');
                    app.static_dyna_menu_data_proc.Value = 'Static';
                end
            
                if(strcmp(app.static_dyna_menu_data_proc.Value,'Static'))
                    PD_legend = strings(1,size(passive_data.static_data.PD,2));
                    for k = 1:size(passive_data.static_data.PD,2)
            
                        mat_data = [passive_data.static_data.loading.biaxial_Pd.(passive_data.static_data.PD{k});...
                            passive_data.static_data.unloading.biaxial_Pd.(passive_data.static_data.PD{k})];
                        PD_name = passive_data.static_data.PD{k};
                        PD_legend{k} = ['\lambda_z = ' PD_name(4:end) '% in vivo']; % plot legend entry for the current test
                        
                        % pressure-diameter plot
                        plot(app.axes1_dat_proc_tab,mat_data.("Inner diameter [um]")/10^3, mat_data.("Pressure [mmHg]"),colour_scheme{k}) %,'DisplayName',PD_legend{k}
                        xlabel(app.axes1_dat_proc_tab,'Inner diameter [mm]','FontSize',16)
                        ylabel(app.axes1_dat_proc_tab,'Pressure [mmHg]','FontSize',16)
                 
                        % force-pressure plot
                        plot(app.axes2_dat_proc_tab,mat_data.("Pressure [mmHg]"),mat_data.("Transducer axial force [g]"),colour_scheme{k}); %,'DisplayName',PD_legend{k}
                        xlabel(app.axes2_dat_proc_tab,'Pressure [mmHg]','FontSize',16)
                        ylabel(app.axes2_dat_proc_tab,'Axial force [g]','FontSize',16)
                    end
                    legend(app.axes1_dat_proc_tab,PD_legend,'Location','NorthWest','FontSize',13);
            
                    FL_legend = strings(1,size(passive_data.static_data.FL,2));
                    for k = 1:size(passive_data.static_data.FL,2)
           
                        mat_data = [passive_data.static_data.loading.biaxial_Fl.(passive_data.static_data.FL{k});...
                            passive_data.static_data.unloading.biaxial_Fl.(passive_data.static_data.FL{k})];
                        FL_name = passive_data.static_data.FL{k};
                        FL_legend{k} = [FL_name(4:end) ' mmHg']; % entry for plot legend
            
                        % inner diameter-axial stretch plot
                        plot(app.axes3_dat_proc_tab,mat_data.("Axial stretch [-]"),mat_data.("Inner diameter [um]")/10^3,colour_scheme{k});
                        ylabel(app.axes3_dat_proc_tab,'Inner diameter [mm]','FontSize',16)
                        xlabel(app.axes3_dat_proc_tab,'Axial stretch [-]','FontSize',16)
                        
                        % axial force-axial stretch plot
                        plot(app.axes4_dat_proc_tab,mat_data.("Axial stretch [-]"),mat_data.("Transducer axial force [g]"),colour_scheme{k});
                        ylabel(app.axes4_dat_proc_tab,'Axial force [g]','FontSize',16)
                        xlabel(app.axes4_dat_proc_tab,'Axial stretch [-]','FontSize',16)
                    end
                    legend(app.axes4_dat_proc_tab,FL_legend,'Location','NorthWest','FontSize',13);
            
                elseif(strcmp(app.static_dyna_menu_data_proc.Value(1:7),'Dynamic'))
            
                    cla(app.axes3_dat_proc_tab);
                    cla(app.axes4_dat_proc_tab)

                    mat_data = [passive_data.static_data.loading.biaxial_Pd.PD_100;...
                        passive_data.static_data.unloading.biaxial_Pd.PD_100];
                    PD_legend = '\lambda_z = 100% in vivo'; % plot legend entry for the current test
            
                    % reference quasi-static pressure-diameter plot
                    plot(app.axes1_dat_proc_tab,mat_data.('Inner diameter [um]')/10^3, mat_data.('Pressure [mmHg]'),'k--')
                    xlabel(app.axes1_dat_proc_tab,'Inner diameter [mm]','FontSize',16)
                    ylabel(app.axes1_dat_proc_tab,'Pressure [mmHg]','FontSize',16)
                    legend(app.axes1_dat_proc_tab,PD_legend,'Location','NorthWest','FontSize',13);
            
                    % reference quasi-static force-pressure plot
                    plot(app.axes2_dat_proc_tab,mat_data.('Pressure [mmHg]'),mat_data.('Transducer axial force [g]'),'k--');
                    xlabel(app.axes2_dat_proc_tab,'Pressure [mmHg]','FontSize',16)
                    ylabel(app.axes2_dat_proc_tab,'Axial force [g]','FontSize',16)
                    
                    j = 0;
                    PD_SBP = strings(1,size(passive_data.dynamic_data.PD,2));
                    PD_Hz = strings(1,size(passive_data.dynamic_data.PD,2));

                    pressureSave = 0;

                    for k = 1:size(passive_data.dynamic_data.PD,2)
                        locatorUnderscore = find(passive_data.dynamic_data.PD{k} == '_');
                        pressure = str2double(passive_data.dynamic_data.PD{k}(locatorUnderscore(1)+1:locatorUnderscore(2)-1));
                        
                        if(pressure ~= pressureSave)
                            mat_data = passive_data.dynamic_data.dynamic_PD.(passive_data.dynamic_data.PD{k});
                            PD_name = passive_data.dynamic_data.PD{k};
                            locator = find(PD_name == '_');
                            PD_SBP{k} = PD_name(locator(1)+1:locator(2)-1);
                            if(size(locator,2)==2)
                                PD_Hz{k} = PD_name(locator(2)+1:end);
                            else
                                PD_Hz{k} = PD_name(locator(2)+1:locator(3)-1);
                            end
    
                            if(strcmp(PD_name(locator(3)+1:end),'physio') && (strcmp(app.static_dyna_menu_data_proc.Value,'Dynamic (physiological)')))
                                j = j+1;
                                % pessure-diameter plot
                                new_legend_entry = [PD_SBP{k} ' mmHg, ' num2str(round(str2double(PD_Hz{k})/100)/10) ' Hz']; % legend entry string
                                plot(app.axes1_dat_proc_tab,mat_data.("Inner diameter [um]")/10^3, mat_data.("Pressure [mmHg]"), colour_scheme{j}, 'DisplayName',new_legend_entry)
                    
                                % force-pressure plot
                                plot(app.axes2_dat_proc_tab,mat_data.("Pressure [mmHg]"),mat_data.("Transducer axial force [g]"), colour_scheme{j});
                            elseif(strcmp(PD_name(locator(3)+1:end),'sine') && (strcmp(app.static_dyna_menu_data_proc.Value,'Dynamic (sine)')))
                                j = j+1;
                                % pessure-diameter plot
                                new_legend_entry = [PD_SBP{k} ' mmHg, ' num2str(round(str2double(PD_Hz{k})/100)/10) ' Hz']; % legend entry string
                                plot(app.axes1_dat_proc_tab,mat_data.("Inner diameter [um]")/10^3, mat_data.("Pressure [mmHg]"), colour_scheme{j}, 'DisplayName',new_legend_entry)
                    
                                % force-pressure plot
                                plot(app.axes2_dat_proc_tab,mat_data.("Pressure [mmHg]"),mat_data.("Transducer axial force [g]"), colour_scheme{j});
                            end

                            pressureSave = pressure;
                        end
                    end            
                end
            else
                app = appErrorDialogBox(app,'off','off','on','ERROR: Missing data',...
                            'Please load data first.');
            end

            hold (app.axes1_dat_proc_tab,'off')
            hold (app.axes2_dat_proc_tab,'off')
            hold (app.axes3_dat_proc_tab,'off')
            hold (app.axes4_dat_proc_tab,'off')
        end

        % Value changed function: axial_stretch_menu
        function inVivoAxialStretchGraphChoice(app, event)
            colour_scheme = {'r','b','g','m','k'}; % colour scheme for plots
            
            if(strcmp(app.axial_stretch_menu.Value,'Raw data'))
                graphChoiceDataProcessing(app, event);
            elseif(strcmp(app.axial_stretch_menu.Value,'In vivo axial stretch'))
                if(isfield(app.load_data_dat_proc.UserData,'passive'))
                    passive = app.load_data_dat_proc.UserData.passive;
                else
                    passive = app.load_data_dat_proc.UserData.experimental_data.passive;
                end
                                
                x_lb = app.axes4_dat_proc_tab.XLim(1);
                x_ub = app.axes4_dat_proc_tab.XLim(2);
                y_lb = app.axes4_dat_proc_tab.YLim(1);
                y_ub = app.axes4_dat_proc_tab.YLim(2);
                                
                FL = ["FL_60", "FL_100", "FL_140"];
                legend_entry_FL = strings(1,3);
                for i = 1:3
                    dataMat = passive.preliminary_analysis.in_vivo_axial_stretch.curves.(FL{i});
                    if(i>1)
                        hold(app.axes4_dat_proc_tab,'on');
                    end
                    plot(app.axes4_dat_proc_tab,dataMat.(1),dataMat.(2),colour_scheme{i});

                    legend_entry_FL{i} = [FL{i}(4:end) ' mmHg'];
                end

                plot(app.axes4_dat_proc_tab,passive.preliminary_analysis.in_vivo_axial_stretch.intersection_points.(1), passive.preliminary_analysis.in_vivo_axial_stretch.intersection_points.(2),'kx','LineWidth',3)
                xlabel(app.axes4_dat_proc_tab,'Axial stretch [-]','FontSize',16)
                ylabel(app.axes4_dat_proc_tab,'Axial force [g]','FontSize',16)

                legend_entry_FL{length(legend_entry_FL)+1} = 'Intersection points';

                legend(app.axes4_dat_proc_tab, legend_entry_FL(1:i),'Location','NorthWest');

                hold(app.axes4_dat_proc_tab,'off');

                axis(app.axes4_dat_proc_tab, [x_lb x_ub y_lb y_ub]);
            end
        end

        % Button pushed function: Const_mod_tab_button
        function constitutiveModellingTab(app, event)
            set(app.Measurements_tab,'visible','on');
            set(app.dat_proc_tab,'visible','off');
            set(app.simulate_tab,'visible','off');
            set(app.static_dyna_menu_data_proc,'visible','off');
            set(app.text_mmHg_slider,'visible','off');
            set(app.data_analysis_tab_button,'FontWeight','normal');
            set(app.Const_mod_tab_button,'FontWeight','bold');
            set(app.dat_proc_tab_button,'FontWeight','normal');

            set(app.data_analysis_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.Const_mod_tab_button,'BackgroundColor','0.94,0.94,0.94');
            set(app.dat_proc_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.simulate_button,'BackgroundColor','0.86,0.86,0.86');
        end

        % Button pushed function: Load_data_mod_tab
        function loadDataModellingTab(app, event)
            app.Load_data_mod_tab.BackgroundColor = [0.86,0.86,0.86];
            loadDataDataAnalysisTab(app, event)
            app.Load_data_mod_tab.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: graph_choice_mod_tab, 
        % ...and 1 other component
        function graphChoiceModellingTab(app, event)
            
            plotGraphsModellingTab(app,app.Load_data_mod_tab.UserData.fit_data);
        end

        % Button pushed function: data_analysis_tab_button
        function dataAnalysisTab(app, event)
            set(app.Measurements_tab,'visible','off');
            set(app.dat_proc_tab,'visible','off');
            set(app.simulate_tab,'visible','off');
            set(app.static_dyna_menu_data_proc,'visible','on');
            set(app.text_mmHg_slider,'visible','on');
            set(app.data_analysis_tab_button,'FontWeight','bold');
            set(app.Const_mod_tab_button,'FontWeight','normal');
            set(app.dat_proc_tab_button,'FontWeight','normal');
            set(app.simulate_button,'FontWeight','normal');

            set(app.data_analysis_tab_button,'BackgroundColor','0.94,0.94,0.94');
            set(app.Const_mod_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.dat_proc_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.simulate_button,'BackgroundColor','0.86,0.86,0.86');
        end

        % Button pushed function: load_data_button
        function loadDataDataAnalysisTab(app, event)
            
            app.load_data_button.BackgroundColor = [0.86,0.86,0.86];
            app.data_proc_dial.Value = 0;

            [FileName, Dir] = uigetfile('*.mat','*.xlsx', 'Select a data file');

            if(prod(FileName~=0) && prod(Dir~=0))
                FileName = fullfile(Dir, FileName);
                data = load(FileName);
                
                cla(app.axes1_mod_tab);
                cla(app.axes2_mod_tab);
                cla(app.axes1);
                cla(app.axes2);

                queuing = 0;
                elastic_save = 0;
                in_vivo_ref_save = 0;
                visco_save = 0;
                active_save = 0;
                axial_sweeps_check_save = 0;
                PD_save = 0;
                
                if(isfield(data, 'passive') || isfield(data,'experimental_data'))
                    set(app.Measurements_tab,'visible','on');                    
                    [app,fit_data] = ParameterEstimationFun(app,event,queuing,FileName,data);

                    app.Load_data_mod_tab.UserData.FileName = sprintf('fit%s.mat',app.text_error_box.Value{1});
                    app.Load_data_mod_tab.UserData.fit_data = fit_data;
                                    
                elseif(isfield(data, 'fit_data'))
                    app.Load_data_mod_tab.UserData.fit_data = data.fit_data;
                    app.Load_data_mod_tab.UserData.FileName = FileName;
                    set(app.Measurements_tab,'visible','off');
                    app.Const_mod_tab_button.BackgroundColor = [0.86,0.86,0.86];
                    app.Const_mod_tab_button.FontWeight = 'normal';
                    app.data_analysis_tab_button.BackgroundColor = [0.94,0.94,0.94];
                    app.data_analysis_tab_button.FontWeight = 'bold';
                    app.status_txt.Text = 'Results from a previous analysis.';

                else
                    for i_sample = 1:size(data.file_list,1)
                        queuing = 1;
                        SampleFile = fullfile(Dir, data.file_list{i_sample});

                        [app,fit_data,elastic_save,visco_save,active_save,in_vivo_ref_save,axial_sweeps_check_save,PD_save] = ParameterEstimationFun(app,event,queuing,SampleFile,data,elastic_save,visco_save,active_save,in_vivo_ref_save,axial_sweeps_check_save,PD_save,i_sample);
                        app.Load_data_mod_tab.UserData.fit_data = fit_data;
                    end
                end
                
                graphChoiceDataAnalysisTab(app, event);
                graphChoiceModellingTab(app, event);
                
                % Retriving experimental data file to be plotted on data
                % processing tab
                if(isfile(app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data))
                    app.load_data_dat_proc.UserData = load(app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data);
                    if(isfield(app.load_data_dat_proc.UserData,'passive'))
                        app.unloaded_Ro.Value = num2str(app.load_data_dat_proc.UserData.passive.OD/2);
                        app.unloaded_Ri.Value = num2str(app.load_data_dat_proc.UserData.passive.OD/2-app.load_data_dat_proc.UserData.passive.H);
                        app.unloaded_lz.Value = num2str(app.load_data_dat_proc.UserData.passive.L);
                    else
                        app.unloaded_Ro.Value = num2str(app.load_data_dat_proc.UserData.experimental_data.passive.OD/2);
                        app.unloaded_Ri.Value = num2str(app.load_data_dat_proc.UserData.experimental_data.passive.OD/2-app.load_data_dat_proc.UserData.experimental_data.passive.H);
                        app.unloaded_lz.Value = num2str(app.load_data_dat_proc.UserData.experimental_data.passive.L);
                    end
    
                    locator = find(app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data=='_');
                    app.mouse_ID_text.Value = app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data(locator(end-1)+1:locator(end)-1);

                    graphChoiceDataProcessing(app, event);
                else
                    messageText = ['The file linked to this analysis (',app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data,...
                         ') is not found in the designated folder. Would you like to update the file location?'];
                    app = appErrorDialogBox(app,'on','on','off','WARNING: Missing file.',messageText);
                    
                    if(strcmp(app.protocol_menu.Value,'Custom'))
                        [protocolFile_name, Dir] = uigetfile('*.mat', 'Select a data file');
                        app.protocolFile = fullfile(Dir, protocolFile_name);
                    end
                    
                    if(app.Yes_button_error.Value == 1)
                        [FileName_corrected, Dir] = uigetfile('*.mat', 'Select a data file');
                        FileName_corrected = fullfile(Dir, FileName_corrected);
                        app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data = FileName_corrected;
                        
                        fit_data = app.Load_data_mod_tab.UserData.fit_data;
                        save(app.Load_data_mod_tab.UserData.FileName, 'fit_data');
                        
                        app.load_data_dat_proc.UserData = [];
                        app.load_data_dat_proc.UserData = load(app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data);

                        if(isfield(app.load_data_dat_proc.UserData,'passive'))
                            app.unloaded_Ro.Value = num2str(app.load_data_dat_proc.UserData.passive.OD/2);
                            app.unloaded_Ri.Value = num2str(app.load_data_dat_proc.UserData.passive.OD/2-app.load_data_dat_proc.UserData.passive.H);
                            app.unloaded_lz.Value = num2str(app.load_data_dat_proc.UserData.passive.L);
            
                            locator = find(app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data=='_');
                            app.mouse_ID_text.Value = app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data(locator(end-1)+1:locator(end)-1);
        
                            graphChoiceDataProcessing(app, event);
                        elseif(isfield(app.load_data_dat_proc.UserData.experimental_data,'passive'))
                            app.unloaded_Ro.Value = num2str(app.load_data_dat_proc.UserData.experimental_data.passive.OD/2);
                            app.unloaded_Ri.Value = num2str(app.load_data_dat_proc.UserData.experimental_data.passive.OD/2-app.load_data_dat_proc.UserData.experimental_data.passive.H);
                            app.unloaded_lz.Value = num2str(app.load_data_dat_proc.UserData.experimental_data.passive.L);
            
                            locator = find(app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data=='_');
                            app.mouse_ID_text.Value = app.Load_data_mod_tab.UserData.fit_data.file_name_exp_data(locator(end-1)+1:locator(end)-1);
        
                            graphChoiceDataProcessing(app, event);
                        else
                            app = appErrorDialogBox(app,'off','off','on','WARNING: Wrong file',...
                            'The selected file does not contain the relevant information. Figures in the "Data processing" tab could not be updated.');
                        end
                    else
                        app = appErrorDialogBox(app,'off','off','on','WARNING: Missing file',...
                            'I respect your decision (:-)). However, figures in the "Data processing" tab could not be updated.');
                    end
                end

                
                % Loading fitting outcome data
                fit_data = app.Load_data_mod_tab.UserData.fit_data;
                if(size(fit_data.fittingQuality,1)>1)
                    app.static_R2_value.Text = num2str(round(fit_data.fittingQuality.(1)(2)*100)/100);
                
                    app.dynamic_R2_value.Text = num2str(round(fit_data.fittingQuality.(1)(1)*100)/100);
                    app.memory_fun.Text = fit_data.modelFormulation.viscousModel.funName;
                    app.N_param_dynamic.Text = num2str(fit_data.modelFormulation.viscousModel.nParam*2);
                else
                    app.static_R2_value.Text = num2str(round(fit_data.fittingQuality.(1)*100)/100);
                end
                
                app.in_vivo_axial_stretch_mod.Text = num2str(round(fit_data.vesselConfigurations.reference_configuration.("Axial length [mm]")/fit_data.vesselConfigurations.unloaded_configuration.("Axial length [mm]")*mean(fit_data.modBehaviour.PD_100.("Axial stretch [-]"))*100)/100);
                if(isfield(fit_data.fittedCurves,'quasiStaticFit'))
                    app.in_vivo_axial_stretch_exp.Text = num2str(round(fit_data.vesselConfigurations.reference_configuration.("Axial length [mm]")/fit_data.vesselConfigurations.unloaded_configuration.("Axial length [mm]")*mean(fit_data.fittedCurves.quasiStaticFit.PD_100.("Axial stretch [-]"))*100)/100);
                else
                    app.in_vivo_axial_stretch_exp.Text = num2str(round(fit_data.vesselConfigurations.reference_configuration.("Axial length [mm]")/fit_data.vesselConfigurations.unloaded_configuration.("Axial length [mm]")*mean(fit_data.fittedCurves.staticFit.PD_100.("Axial stretch [-]"))*100)/100);
                end
                
                app.SEF.Text = fit_data.modelFormulation.elasticModel.modelName;
                app.N_param_static.Text = num2str(size(fit_data.modelParameters.staticSEFparameters,1));
                app.N_param_constrained.Text = num2str(size(fit_data.modelParameters.staticSEFparameters,1)-length(fit_data.modelFormulation.elasticModel.lb));
                
                if(fit_data.vesselConfigurations.reference_configuration.("Axial length [mm]")==fit_data.vesselConfigurations.unloaded_configuration.("Axial length [mm]"))
                    app.ref_conf.Text = 'Unloaded vessel.';
                else
                    app.ref_conf.Text = 'in vivo (exp. axial stretch and 100 mmHg).';
                end
            end

            app.load_data_button.BackgroundColor = [0.96,0.96,0.96];
            
        end

        % Button pushed function: calculate
        function calculateBiomechanicalVariables(app, event)
            fit_data = app.Load_data_mod_tab.UserData.fit_data;
            
            % Checking pressure input
            if(app.pressure_slider.Value~=0)
                targetP = str2double(app.pressure_value.Value);
            
                if(strcmp(app.SBP_value.Value,'....') || strcmp(app.DBP_value.Value,'....'))
                    SBP = NaN;
                    DBP = NaN;
                else
                    SBP = str2double(app.SBP_value.Value);
                    DBP = str2double(app.DBP_value.Value);

                    if(SBP<targetP(1,1) || DBP>targetP(1,1))
                        SBP = NaN;
                        DBP = NaN;
                        app = appErrorDialogBox(app,'off','off','on','ERROR: Pressure value',...
                            'Set pressure must be within the SBP-DBP range. Parameter values not written in the .mat file.');
                    end
                end
                
                % Calculating variables at set pressure range
                fit_data = biomechVar_GUI(targetP, SBP, DBP, fit_data, app);
                
                % Write on app
                app.sigmatt_value.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Circumferential stress [MPa]")*10000)/10000);
                app.sigmazz_value.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Axial stress [MPa]")*10000)/10000);
                app.Ctttt_value.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Circumferential stiffness [MPa]")*10000)/10000);
                app.Czzzz_value.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Axial stiffness [MPa]")*10000)/10000);
                app.circ_coll_LB.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Collagen circ.load-bearing [%]")*10000)/10000);
                app.axial_coll_LB.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Collagen axial load-bearing [%]")*10000)/10000);
                app.circ_el_LB.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Elastin circ. load-bearing [%]")*10000)/10000);
                app.axial_el_LB.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Elastin axial load-bearing [%]")*10000)/10000);
                        
                if((~isnan(SBP)) && (~isnan(DBP)))
                    app.PWV_value.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("PWV [m/s]")*10000)/10000);
                    app.distensibility_value.Text = num2str(round(fit_data.bioMechVar.(size(fit_data.bioMechVar,2))("Distensibility [1/MPa]")*10000)/10000);
                else
                    app.PWV_value.Text = '....';
                    app.distensibility_value.Text = '....';
                end
            end
            app.Load_data_mod_tab.UserData.fit_data = fit_data;
        end

        % Button pushed function: clear
        function clearBiomechanicalvariables(app, event)
            % clearing variables
            if(isfield(app.Load_data_mod_tab.UserData.fit_data, 'bioMechVar'))
                fit_data = rmfield(app.Load_data_mod_tab.UserData.fit_data, 'bioMechVar');
                save(app.Load_data_mod_tab.UserData.FileName, 'fit_data');            
            end
            
            % updating app 
            app.sigmatt_value.Text = '....';
            app.sigmazz_value.Text = '....';
            app.Ctttt_value.Text = '....';
            app.Czzzz_value.Text = '....';
            app.PWV_value.Text = '....';
            app.distensibility_value.Text = '....';
        end

        % Button pushed function: save
        function saveBiomechanicalVariables(app, event)
            fit_data = app.Load_data_mod_tab.UserData.fit_data;
            save(app.Load_data_mod_tab.UserData.FileName, 'fit_data')
        end

        % Button pushed function: Reset
        function resetDataAnalysisTab(app, event)
            app.SBP_value.Value = '....';
            app.DBP_value.Value = '....';
            app.sigmatt_value.Text = '....';
            app.sigmazz_value.Text = '....';
            app.Ctttt_value.Text = '....';
            app.Czzzz_value.Text = '....';
            app.PWV_value.Text = '....';
            app.distensibility_value.Text = '....';
            app.pressure_slider.Value = 0;
            app.pressure_value.Value = '....';
            
            graphChoiceDataAnalysisTab(app, event)
        end

        % Value changed function: DBP_value, SBP_value, graph_choice
        function graphChoiceDataAnalysisTab(app, event)
            fit_data = app.Load_data_mod_tab.UserData.fit_data;

            if(strcmp(app.graph_choice.Value,'Pressure and force'))
                plot(app.axes1, 2*fit_data.modBehaviour.PD_100.("Inner radius [mm]"), fit_data.modBehaviour.PD_100.("Pressure [mmHg]"),'k-o')
                ylabel(app.axes1,'Pressure [mmHg]','FontSize',16);
                xlabel(app.axes1,'Internal diameter [mm]','FontSize',16);
                axis(app.axes1,[0.95*2*min(fit_data.modBehaviour.PD_100.("Inner radius [mm]")) 1.05*2*max(fit_data.modBehaviour.PD_100.("Inner radius [mm]")) 0 200]);
                legend(app.axes1,'off');

                plot(app.axes2, fit_data.modBehaviour.PD_100.("Pressure [mmHg]"), fit_data.modBehaviour.PD_100.("Axial force [g]"),'k-o')
                ylabel(app.axes2,'Transducer force [g]','FontSize',16);
                xlabel(app.axes2,'Pressure [mmHg]','FontSize',16);
                axis(app.axes2,[0 200 0 2*max(fit_data.modBehaviour.PD_100.("Axial force [g]"))]);
            
            elseif(strcmp(app.graph_choice.Value,'Stresses'))
                plot(app.axes1, fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"), fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]"),'k-o')
                ylabel(app.axes1,'Circumferential stress [MPa]','FontSize',16);
                xlabel(app.axes1,'Circumferential stretch [-]','FontSize',16);
                lb_x = 0.95*min(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                ub_x = 1.05*max(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                ub_y = 1.05*max(max(fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")),max(fit_data.modBehaviour.PD_100.("Axial stress [MPa]")));
                axis(app.axes1,[lb_x ub_x 0 ub_y]);
                legend(app.axes1,'off');

                plot(app.axes2, fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"), fit_data.modBehaviour.PD_100.("Axial stress [MPa]"),'k-o')
                ylabel(app.axes2,'Axial stress [MPa]','FontSize',16);
                xlabel(app.axes2,'Circumferential stretch [-]','FontSize',16);
                lb_x = 0.95*min(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                ub_x = 1.05*max(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                ub_y = 1.05*max(max(fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")),max(fit_data.modBehaviour.PD_100.("Axial stress [MPa]")));
                axis(app.axes2,[lb_x ub_x 0 ub_y]);
            elseif(strcmp(app.graph_choice.Value,'Load bearing (P&F)'))
                if(size(table2array(fit_data.modBehaviour.PD_100),2) == 17)
                    a = area(app.axes1, 2*fit_data.modBehaviour.PD_100.("Inner radius [mm]"), [fit_data.modBehaviour.PD_100.("Pressure [mmHg]").*fit_data.modBehaviour.PD_100.("Elastin circ. load bearing [%]")/100 fit_data.modBehaviour.PD_100.("Pressure [mmHg]").*fit_data.modBehaviour.PD_100.("Collagen circ. load bearing [%]")/100]);
                    a(1).FaceColor = [0.9 0.3 0];
                    a(2).FaceColor = [0.2 0.8 0.5];
                    ylabel(app.axes1,'Pressure [mmHg]','FontSize',16);
                    xlabel(app.axes1,'Internal diameter [mm]','FontSize',16);
                    legend(app.axes1,{'Elastin' 'Collagen'},'Location','northwest','FontSize',14)
                    axis(app.axes1,[0.95*2*min(fit_data.modBehaviour.PD_100.("Inner radius [mm]")) 1.05*2*max(fit_data.modBehaviour.PD_100.("Inner radius [mm]")) 0 200]);

                    a = area(app.axes2, fit_data.modBehaviour.PD_100.("Pressure [mmHg]"), [fit_data.modBehaviour.PD_100.("Axial force [g]").*fit_data.modBehaviour.PD_100.("Elastin axial load bearing [%]")/100 fit_data.modBehaviour.PD_100.("Axial force [g]").*fit_data.modBehaviour.PD_100.("Collagen axial load bearing [%]")/100]);
                    a(1).FaceColor = [0.9 0.3 0];
                    a(2).FaceColor = [0.2 0.8 0.5];
                    ylabel(app.axes2,'Transducer force [g]','FontSize',16);
                    xlabel(app.axes2,'Pressure [mmHg]','FontSize',16);
                    axis(app.axes2,[0 200 0 2*max(fit_data.modBehaviour.PD_100.("Axial force [g]"))]);
                else
                    app = appErrorDialogBox(app,'off','off','on','ERROR: Fit file from an older version of this app',...
                            'This fit file was created with an older version of this app. This graph cannot be plotted at this moment.');

                    app.graph_choice.Value = 'Pressure and force';
                end
            elseif(strcmp(app.graph_choice.Value,'Load bearing (stresses)'))
                if(size(table2array(fit_data.modBehaviour.PD_100),2) == 17)
                    a = area(app.axes1, fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"), [fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]").*fit_data.modBehaviour.PD_100.("Elastin circ. load bearing [%]")/100 fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]").*fit_data.modBehaviour.PD_100.("Collagen circ. load bearing [%]")/100]);
                    a(1).FaceColor = [0.9 0.3 0];
                    a(2).FaceColor = [0.2 0.8 0.5];
                    ylabel(app.axes1,'Circumferential stress [MPa]','FontSize',16);
                    xlabel(app.axes1,'Circumferential stretch [-]','FontSize',16);
                    lb_x = 0.95*min(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                    ub_x = 1.05*max(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                    ub_y = 1.05*max(max(fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")),max(fit_data.modBehaviour.PD_100.("Axial stress [MPa]")));
                    axis(app.axes1,[lb_x ub_x 0 ub_y]);
                    legend(app.axes1,{'Elastin' 'Collagen'},'Location','northwest','FontSize',14)

                    a = area(app.axes2, fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"), [fit_data.modBehaviour.PD_100.("Axial stress [MPa]").*fit_data.modBehaviour.PD_100.("Elastin axial load bearing [%]")/100 fit_data.modBehaviour.PD_100.("Axial stress [MPa]").*fit_data.modBehaviour.PD_100.("Collagen axial load bearing [%]")/100]);
                    a(1).FaceColor = [0.9 0.3 0];
                    a(2).FaceColor = [0.2 0.8 0.5];
                    ylabel(app.axes2,'Axial stress [MPa]','FontSize',16);
                    xlabel(app.axes2,'Circumferential stretch [-]','FontSize',16);
                    lb_x = 0.95*min(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                    ub_x = 1.05*max(fit_data.modBehaviour.PD_100.("Circumferential stretch [-]"));
                    ub_y = 1.05*max(max(fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")),max(fit_data.modBehaviour.PD_100.("Axial stress [MPa]")));
                    axis(app.axes2,[lb_x ub_x 0 ub_y]);
                else   
                    app = appErrorDialogBox(app,'off','off','on','ERROR: Fit file from an older version of this app',...
                            'This fit file was created with an older version of this app. This graph cannot be plotted at this moment.');

                    app.graph_choice.Value = 'Pressure and force';
                end
            end
            
            if(~strcmp(app.pressure_value.Value,'....') && (app.pressure_slider.Value~=0))            
                [~,locator] = min(abs(str2double(app.pressure_value.Value)-fit_data.modBehaviour.PD_100.("Pressure [mmHg]")));
                                        
                if(~strcmp(app.SBP_value.Value,'....') && ~strcmp(app.DBP_value.Value,'....'))
                    SBP = str2double(app.SBP_value.Value);
                    DBP = str2double(app.DBP_value.Value);
            
                    [~,locator_lb] = min(abs(DBP-fit_data.modBehaviour.PD_100.("Pressure [mmHg]")));
                    [~,locator_ub] = min(abs(SBP-fit_data.modBehaviour.PD_100.("Pressure [mmHg]")));
            
                    if(strcmp(app.graph_choice.Value,'Pressure and force'))
                        hold(app.axes1, 'on')
                        if((locator_lb<=locator) && (locator_ub>=locator))
                            plot(app.axes1,2*fit_data.modBehaviour.PD_100.("Inner radius [mm]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Pressure [mmHg]")(locator_lb:locator_ub),'b','LineWidth',4)
                        else
                            plot(app.axes1,2*fit_data.modBehaviour.PD_100.("Inner radius [mm]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Pressure [mmHg]")(locator_lb:locator_ub),'m','LineWidth',4)
                        end
                        hold(app.axes1, 'off')
            
                        hold(app.axes2, 'on')
                        if((locator_lb<=locator) && (locator_ub>=locator))
                            plot(app.axes2,fit_data.modBehaviour.PD_100.("Pressure [mmHg]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Axial force [g]")(locator_lb:locator_ub),'b','LineWidth',4)
                        else
                            plot(app.axes2,fit_data.modBehaviour.PD_100.("Pressure [mmHg]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Axial force [g]")(locator_lb:locator_ub),'m','LineWidth',4)
                        end
                        hold(app.axes2, 'off')
            
                    elseif(strcmp(app.graph_choice.Value,'Stresses'))
                        hold(app.axes1, 'on')
                        if((locator_lb<=locator) && (locator_ub>=locator))
                            plot(app.axes1,fit_data.modBehaviour.PD_100.("Circumferential stretch [-]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")(locator_lb:locator_ub),'b','LineWidth',4)
                        else
                            plot(app.axes1,fit_data.modBehaviour.PD_100.("Circumferential stretch [-]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")(locator_lb:locator_ub),'m','LineWidth',4)
                        end
                        hold(app.axes1, 'off')
            
                        hold(app.axes2, 'on')
                        if((locator_lb<=locator) && (locator_ub>=locator))
                            plot(app.axes2,fit_data.modBehaviour.PD_100.("Circumferential stretch [-]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Axial stress [MPa]")(locator_lb:locator_ub),'b','LineWidth',4)
                        else
                            plot(app.axes2,fit_data.modBehaviour.PD_100.("Circumferential stretch [-]")(locator_lb:locator_ub), fit_data.modBehaviour.PD_100.("Axial stress [MPa]")(locator_lb:locator_ub),'m','LineWidth',4)
                        end
                        hold(app.axes2, 'off')
                    end
                end

                if(strcmp(app.graph_choice.Value,'Pressure and force'))
                    hold(app.axes1,'on');
                    plot(app.axes1, 2*fit_data.modBehaviour.PD_100.("Inner radius [mm]")(locator), fit_data.modBehaviour.PD_100.("Pressure [mmHg]")(locator),'r-*','LineWidth',10)
                    ylabel(app.axes1,'Pressure [mmHg]','FontSize',16);
                    xlabel(app.axes1,'Internal diameter [mm]','FontSize',16);
                    axis(app.axes1,[0.95*2*min(fit_data.modBehaviour.PD_100.("Inner radius [mm]")) 1.05*2*max(fit_data.modBehaviour.PD_100.("Inner radius [mm]")) 0 200]);
                    legend(app.axes1,'off');
                    hold(app.axes1,'off');
                    
                    hold(app.axes2,'on');
                    plot(app.axes2, fit_data.modBehaviour.PD_100.("Pressure [mmHg]")(locator), fit_data.modBehaviour.PD_100.("Axial force [g]")(locator),'r-*','LineWidth',10)
                    ylabel(app.axes2,'Transducer force [g]','FontSize',16);
                    xlabel(app.axes2,'Pressure [mmHg]','FontSize',16);
                    axis(app.axes2,[0 200 0 2*max(fit_data.modBehaviour.PD_100.("Axial force [g]"))]);
                    hold(app.axes2,'off');
            
                elseif(strcmp(app.graph_choice.Value,'Stresses'))
                    hold(app.axes1,'on');
                    plot(app.axes1, fit_data.modBehaviour.PD_100.("Circumferential stretch [-]")(locator), fit_data.modBehaviour.PD_100.("Circumferential stress [MPa]")(locator),'r-*','LineWidth',10)
                    ylabel(app.axes1,'Circumferential stress [MPa]','FontSize',16);
                    xlabel(app.axes1,'Circumferential stretch [-]','FontSize',16);
                    legend(app.axes1,'off');
                    hold(app.axes1,'off');
                    
                    hold(app.axes2,'on');
                    plot(app.axes2, fit_data.modBehaviour.PD_100.("Circumferential stretch [-]")(locator), fit_data.modBehaviour.PD_100.("Axial stress [MPa]")(locator),'r-*','LineWidth',10)
                    ylabel(app.axes2,'Axial stress [MPa]','FontSize',16);
                    xlabel(app.axes2,'Circumferential stretch [-]','FontSize',16);
                    hold(app.axes2,'off');
                end
            end
        end

        % Value changed function: pressure_slider
        function pressureSliderModified(app, event)
            set_P = round(app.pressure_slider.Value);
            app.pressure_value.Value = num2str(set_P);  

            graphChoiceDataAnalysisTab(app, event)                 
        end

        % Value changed function: pressure_value
        function pressureValueModified(app, event)
            pressureValue = str2double(app.pressure_value.Value);

            if((pressureValue<=200) && (pressureValue>=0))
                app.pressure_slider.Value = round(pressureValue);
                pressureSliderModified(app, event);
            else
                app.pressure_value.Value = '....';
            end
        end

        % Button pushed function: simulate_button
        function simulateTab(app, event)
            set(app.Measurements_tab,'visible','off');
            set(app.dat_proc_tab,'visible','off');
            set(app.simulate_tab,'visible','on');
            set(app.static_dyna_menu_data_proc,'visible','off');
            set(app.text_mmHg_slider,'visible','off');
            set(app.data_analysis_tab_button,'FontWeight','normal');
            set(app.Const_mod_tab_button,'FontWeight','normal');
            set(app.dat_proc_tab_button,'FontWeight','normal');
            set(app.simulate_button,'FontWeight','bold');

            set(app.data_analysis_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.Const_mod_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.dat_proc_tab_button,'BackgroundColor','0.86,0.86,0.86');
            set(app.simulate_button,'BackgroundColor','0.94,0.94,0.94');
        end

        % Value changed function: waveform_type_menu
        function waveform_type_Callback(app, event)
            
            SBP = str2double(app.SBP_sim.Value);
            DBP = str2double(app.DBP_sim.Value);
        
            if(SBP<DBP)
                SBP_temp = SBP;
                app.SBP_sim.Value = num2str(DBP);
                app.DBP_sim.Value = num2str(SBP);
                SBP = DBP;
                DBP = SBP_temp;
            end
            meanP = (SBP+DBP)/2;
            freq = str2double(app.Hz_value.Value);

            if(~strcmp(app.SBP_sim.Value,'....') && ~strcmp(app.DBP_sim.Value,'....') && ~strcmp(app.Hz_value.Value,'....'))
                if(strcmp(app.waveform_type_menu.Value,'Pressure sweep'))
                    tV = 0:1/freq/100:1/freq;
                    PV = meanP + (SBP-DBP)/2*sawtooth(2*pi*freq*tV,1/2);
                    triggerV = zeros(1,length(PV)+1);
                    triggerV(2) = 1;
                else
                    tV = 0:1/freq/24:1/freq*10;
                    triggerV = zeros(1,length(tV)+1);
                    triggerV(end-24) = 1;
              
                    if(strcmp(app.waveform_type_menu.Value,'Triangular waveform'))
                        meanP = (SBP+DBP)/2;
                        PV = meanP + (SBP-DBP)/2*sawtooth(2*pi*freq*(tV+0.25/freq),1/2);
        
                    elseif(strcmp(app.waveform_type_menu.Value,'Sine wave'))
                        meanP = (SBP+DBP)/2;
                        PV = meanP+(SBP-DBP)/2*sin(2*pi*freq*tV);
        
                    elseif(strcmp(app.waveform_type_menu.Value, 'Physiological waveform'))
                        tV = 0:1/freq/75:1/freq*10;

                        baselineP = load('P_wave.mat');
                        scaledP = DBP+(SBP-DBP)*baselineP.P_wave;

                        k = 0;
                        PV = zeros(1,length(tV));
                        for i = 1:length(tV)
                            if(k<75)
                                k = k+1;
                            else
                                k = 1;
                            end
                            PV(i) = scaledP(k);
                        end
                    end
                end
                
                if(~strcmp(app.waveform_type_menu.Value,'Pressure sweep'))
                    plot(app.axes3_simulate_tab,tV*1000,PV,'k')
                    xlabel(app.axes3_simulate_tab,'Time [ms]');
                    ylabel(app.axes3_simulate_tab,'Pressure [mmHg]')
                    axis(app.axes3_simulate_tab,[0 1000 DBP-10 SBP+10]);
                else
                    plot(app.axes3_simulate_tab,tV*1000,PV,'k')
                    axis(app.axes3_simulate_tab,[0 1/freq*1000 0 200]);
                    xlabel(app.axes3_simulate_tab,'Time [ms]');
                    ylabel(app.axes3_simulate_tab,'Pressure [mmHg]')
                end
            end

            app.simulate_button.UserData.PV = PV;
            app.simulate_button.UserData.tV = tV;
            app.simulate_button.UserData.triggerV = triggerV;
        end

        % Value changed function: SBP_sim
        function SBP_sim_Callback(app, event)
            if(isempty(str2double(app.SBP_sim.Value)))
                app = appErrorDialogBox(app,'off','off','on','ERROR: Incorrect entry',...
                            'Please insert a number. Characters are not valid inputs.');
                app.SBP_sim.Value = '....';
            else
                if(~strcmp(app.SBP_sim.Value,'....') && ~strcmp(app.DBP_sim.Value,'....') && ~strcmp(app.Hz_value.Value,'....'))
                    waveform_type_Callback(app, event);
                end
            end            
        end

        % Value changed function: DBP_sim
        function DBP_sim_Callback(app, event)
            if(isempty(str2double(app.DBP_sim.Value)))
                app = appErrorDialogBox(app,'off','off','on','ERROR: Incorrect entry',...
                            'Please insert a number. Characters are not valid inputs.');
                app.DBP_sim.Value = '....';
            else
                if(~strcmp(app.SBP_sim.Value,'....') && ~strcmp(app.DBP_sim.Value,'....') && ~strcmp(app.Hz_value.Value,'....'))
                    waveform_type_Callback(app, event);
                end
            end 
        end

        % Value changed function: Hz_value
        function Hz_value_Callback(app, event)
            if(isempty(str2double(app.Hz_value.Value)))
                app = appErrorDialogBox(app,'off','off','on','ERROR: Incorrect entry',...
                            'Please insert a number. Characters are not valid inputs.');

                app.Hz_value.Value = '....';
            else
                if(str2double(app.Hz_value.Value)>10^6)
                    app.Hz_value.Value = '1000000';
                elseif(str2double(app.Hz_value.Value)<-10^6)
                    app.Hz_value.Value = '-0.000001';
                end
                app.Hz_knob.Value = log10(str2double(app.Hz_value.Value));
                if(~strcmp(app.SBP_sim.Value,'....') && ~strcmp(app.DBP_sim.Value,'....') && ~strcmp(app.Hz_value.Value,'....'))
                    waveform_type_Callback(app, event);
                end
            end
        end

        % Value changed function: Hz_knob
        function Hz_knob_Callback(app, event)
            
            app.Hz_value.Value = num2str(10^app.Hz_knob.Value);
            waveform_type_Callback(app, event);
            
        end

        % Button pushed function: RunsimulationButton
        function run_simulation_pressed(app, event)
            axes(app.axes1_simulate_tab);
            cla reset;
            axes(app.axes2_simulate_tab);
            cla reset;    
            
            for iSample = 1:size(app.Load_data_Button_simulate.UserData.fit_data_file,1)
                if(isfield(app.simulate_button.UserData,'PV') && isfield(app.Load_data_Button_simulate.UserData,'fit_data_file'))
                    load(app.Load_data_Button_simulate.UserData.fit_data_file{iSample,1});
                    PV = app.simulate_button.UserData.PV;
                    tV = app.simulate_button.UserData.tV;
                    triggerV = app.simulate_button.UserData.triggerV;
    
                    exportMat = runSimulation(fit_data,PV,tV,app,triggerV);
       
                    if(strcmp(app.graphChoice.Value,'Pressure and force'))
                        plot(app.axes1_simulate_tab,exportMat.('Inner diameter [mm]'),exportMat.('Pressure [mmHg]'))
                        xlabel(app.axes1_simulate_tab,'Inner diameter [mm]');
                        ylabel(app.axes1_simulate_tab,'Pressure [mmHg]');
                        axis(app.axes1_simulate_tab,[0.95*min(exportMat.('Inner diameter [mm]')) 1.05*max(exportMat.('Inner diameter [mm]')),0 1.05*max(exportMat.('Pressure [mmHg]'))])
        
                        plot(app.axes2_simulate_tab,exportMat.('Pressure [mmHg]'),exportMat.("Transducer force [g]"))
                        xlabel(app.axes2_simulate_tab,'Pressure [mmHg]','FontSize',16);
                        ylabel(app.axes2_simulate_tab,'Axial force [g]','FontSize',16);
                        axis(app.axes2_simulate_tab,[0 max(exportMat.('Pressure [mmHg]')),0 2*max(exportMat.("Transducer force [g]"))])
                    else
                        plot(app.axes1_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Circ. stress [MPa]"))
                        xlabel(app.axes1_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                        ylabel(app.axes1_simulate_tab,'Circumferential stress [MPa]','FontSize',16);
                        axis(app.axes1_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Circ. stress [MPa]"))])
        
                        plot(app.axes2_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Axial stress [MPa]"))
                        xlabel(app.axes2_simulate_tab,'Circumferential stretch [-]');
                        ylabel(app.axes2_simulate_tab,'Axial stress [MPa]');
                        axis(app.axes2_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Axial stress [MPa]"))])
                    end
    
                    if(strcmp(app.waveform_type_menu.Value,'Pressure sweep'))
                        test_type = 'PS,';
                    elseif(strcmp(app.waveform_type_menu.Value,'Sine wave'))
                        test_type = 'Sine';
                    elseif(strcmp(app.waveform_type_menu.Value,'Triangular waveform'))
                        test_type = 'Triangular';
                    else
                        test_type = 'Physiological';
                    end
    
                    data_label = [app.DBP_sim.Value '-' app.SBP_sim.Value 'mmHg,' app.Hz_value.Value 'Hz,' app.Axial_stretch_sim.Value '%\lambda_z'];
                    legend_list{1,1} = [test_type data_label];
    
                    app.Load_data_Button_simulate.UserData.temp_data{iSample,1} = exportMat;
                    
                    if(isfield(app.Load_data_Button_simulate.UserData,'test_list'))
                        for iTest = 1:length(app.Load_data_Button_simulate.UserData.test_list)
                            test_name = app.Load_data_Button_simulate.UserData.test_list{iTest,1};
                            exportMat = app.Load_data_Button_simulate.UserData.userSimulations.(test_name);
    
                            if(strcmp(app.graphChoice.Value,'Pressure and force'))
                                hold (app.axes1_simulate_tab,'on');
                                plot(app.axes1_simulate_tab,exportMat.('Inner diameter [mm]'),exportMat.('Pressure [mmHg]'))
                                xlabel(app.axes1_simulate_tab,'Inner diameter [mm]','FontSize',16);
                                ylabel(app.axes1_simulate_tab,'Pressure [mmHg]','FontSize',16);
                                axis(app.axes1_simulate_tab,[0.95*min(exportMat.('Inner diameter [mm]')) 1.05*max(exportMat.('Inner diameter [mm]')),0 1.05*max(exportMat.('Pressure [mmHg]'))])
                                        
                                hold (app.axes2_simulate_tab,'on');
                                plot(app.axes2_simulate_tab,exportMat.('Pressure [mmHg]'),exportMat.("Transducer force [g]"))
                                xlabel(app.axes2_simulate_tab,'Pressure [mmHg]','FontSize',16);
                                ylabel(app.axes2_simulate_tab,'Axial force [g]','FontSize',16);
                                axis(app.axes2_simulate_tab,[0 max(exportMat.('Pressure [mmHg]')),0 2*max(exportMat.("Transducer force [g]"))])
                            else
                                hold (app.axes1_simulate_tab,'on');
                                plot(app.axes1_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Circ. stress [MPa]"))
                                xlabel(app.axes1_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                                ylabel(app.axes1_simulate_tab,'Circumferential stress [MPa]','FontSize',16);
                                axis(app.axes1_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Circ. stress [MPa]"))])
        
                                hold (app.axes2_simulate_tab,'on');
                                plot(app.axes2_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Axial stress [MPa]"))
                                xlabel(app.axes2_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                                ylabel(app.axes2_simulate_tab,'Axial stress [MPa]','FontSize',16);
                                axis(app.axes2_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Axial stress [MPa]"))])
                            end
                            
                            locator = [];
                            for iWord = 1:length(test_name)
                                if(strcmp(test_name(iWord),'_'))
                                    locator = cat(2,locator, iWord);
                                end
                            end
    
                            if(strcmp(test_name(1:locator(1)-1),'Pressure sweep'))
                                test_type = 'PS,';
                            elseif(strcmp(test_name(1:locator(1)-1),'Sine wave'))
                                test_type = 'Sine';
                            elseif(strcmp(test_name(1:locator(1)-1),'Triangular waveform'))
                                test_type = 'Triangular';
                            end
                            data_label = [test_name(locator(end-4)+2:locator(end-3)-1) '-' test_name(locator(end-3)+1:locator(end-2)-1) 'mmHg,' test_name(locator(end-2)+2:locator(end-1)-1) '.' test_name(locator(end-1)+1:locator(end)-1) 'Hz,' test_name(locator(end)+3:end) '%\lambda_z'];
                            legend_list{1+iTest,1} = [test_type data_label];
                        end
                    end
                           
                    hold(app.axes1_simulate_tab,'off');
                    hold(app.axes2_simulate_tab,'off');
                end
                if(strcmp(app.CompareSwitch.Value,'Off'))
                    legend(app.axes1_simulate_tab,legend_list,'Location','northwest','FontSize',14);
                else
                    legend_text = strings(1,2);
                    for i = 1:2
                        text_temp = app.Load_data_Button_simulate.UserData.fit_data_file{i,1};
                        locator = find(text_temp == '/');
                        text_temp = text_temp(locator(end)+1:end-4);
                        locator = find(text_temp == '_');
                        text_temp(locator(3:end)) = ' ';
                        legend_text{i,1} = text_temp(locator(2)+1:end);
                    end
                    legend(app.axes1_simulate_tab,legend_text,'Location','northwest','FontSize',14);
                end
                hold(app.axes1_simulate_tab,'on');
                hold(app.axes2_simulate_tab,'on');
            end
            hold(app.axes1_simulate_tab,'off');
            hold(app.axes2_simulate_tab,'off');
        end

        % Button pushed function: Load_data_Button_simulate
        function load_data_button_simulate(app, event)
            if(strcmp(app.CompareSwitch.Value,'On'))
                nSamples = 2;
                app.SaveButton.Enable = 'Off';
                app.HoldsimulationButton.Enable = 'Off';
            else
                nSamples = 1;
                app.SaveButton.Enable = 'On';
                app.HoldsimulationButton.Enable = 'On';
            end

            if(isfield(app.Load_data_Button_simulate.UserData,'fit_data_file'))
                app.Load_data_Button_simulate.UserData.fit_data_file = {};
            end
            if(isfield(app.Load_data_Button_simulate.UserData,'test_list'))
                app.Load_data_Button_simulate.UserData.test_list = {};
            end
            if(isfield(app.Load_data_Button_simulate.UserData,'userSimulations'))
                app.Load_data_Button_simulate.UserData.userSimulations = [];
            end
            app.Load_data_Button_simulate.UserData.temp_data = {};
            
            for iSample = 1:nSamples
                text_message = ['Select a fit file (' num2str(iSample) '/' num2str(nSamples) ')'];
                [file_fit_data,dir_file_fit_data] = uigetfile('*.mat', text_message);
                file_fit_data_complete = fullfile(dir_file_fit_data, file_fit_data);
                loaded_data = load(file_fit_data_complete);
                                           
                if(~isfield(loaded_data,'fit_data'))
                    app = appErrorDialogBox(app,'off','off','on','ERROR: Wrong file',...
                            'The selected file is not in the correct format. The selected file should contain the results of fitting of experimental data (i.e., a "fit_data_......mat" file).');
              
                else
                    app.Load_data_Button_simulate.UserData.fit_data_file{iSample,1} = file_fit_data_complete;
                end
            end
        end

        % Button pushed function: HoldsimulationButton
        function hold_simulation_button_pushed(app, event)
            if(isfield(app.Load_data_Button_simulate.UserData,'temp_data'))
                load(app.Load_data_Button_simulate.UserData.fit_data_file{1,1});
                HzValue = app.Hz_value.Value;
                locator = find(HzValue == '.');
                if(~isempty(locator))
                    HzValue(locator) = '_';
                else
                    HzValue = [HzValue '_0'];
                end
                test_type = app.waveform_type_menu.Value;
                locator = find(test_type == ' ');
                if(~isempty(locator))
                    test_type(locator) = '_';
                end
                if(strcmp(app.VSMCcontractionSwitch.Value, 'Off'))
                    test_name = [test_type '_P' app.DBP_sim.Value '_' app.SBP_sim.Value '_f' HzValue '_Lz' app.Axial_stretch_sim.Value];
                else
                    test_name = [test_type '_P' app.DBP_sim.Value '_' app.SBP_sim.Value '_f' HzValue '_Lz' app.Axial_stretch_sim.Value '_active'];
                end
                app.Load_data_Button_simulate.UserData.userSimulations.(test_name) = app.Load_data_Button_simulate.UserData.temp_data{1,1};
                
                if(~isfield(app.Load_data_Button_simulate.UserData,'test_list'))
                    app.Load_data_Button_simulate.UserData.test_list{1,1} = test_name;
                else
                    app.Load_data_Button_simulate.UserData.test_list{length(app.Load_data_Button_simulate.UserData.test_list)+1,1} = test_name;
                end

                app.Load_data_Button_simulate.UserData.temp_data = {};
            else
                appErrorDialogBox(app,'off','off','on','ERROR: No simulation data available',...
                    'No simulation data found. Please run a simulation before pressing the "Hold simulation" button.');
            end
%             save(app.Load_data_Button_simulate.UserData.fit_data_file,'fit_data');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Load_data_Button_simulate.UserData.test_list = {};
            app.Load_data_Button_simulate.UserData.userSimulations = [];

            axes(app.axes1_simulate_tab);
            cla reset;
            axes(app.axes2_simulate_tab);
            cla reset;        
            axes(app.axes3_simulate_tab);
            cla reset;    
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            load(app.Load_data_Button_simulate.UserData.fit_data_file{1,1});
            HzValue = app.Hz_value.Value;
            locator = find(HzValue == '.');
            if(~isempty(locator))
                HzValue(locator) = '_';
            else
                HzValue = [HzValue '_0'];
            end
            test_type = app.waveform_type_menu.Value;
            locator = find(test_type == ' ');
            if(~isempty(locator))
                test_type(locator) = '_';
            end

            test_name = [test_type '_P' app.DBP_sim.Value '_' app.SBP_sim.Value '_f' HzValue '_Lz' app.Axial_stretch_sim.Value];
            
            check = 1;
            if(isfield(app.Load_data_Button_simulate.UserData,'test_list'))
                for iTest = 1:length(app.Load_data_Button_simulate.UserData.test_list)
                    if(strcmp(test_name,app.Load_data_Button_simulate.UserData.test_list{iTest,1}))
                        check = check*0;
                    end
                end
            end
            if(check == 1)
                hold_simulation_button_pushed(app, event);
            end
            
            for iTest = 1:length(app.Load_data_Button_simulate.UserData.test_list)
                test_name = app.Load_data_Button_simulate.UserData.test_list{iTest,1};
                exportMat = app.Load_data_Button_simulate.UserData.userSimulations.(test_name);

                fit_data.userSimulations.(test_name) = exportMat; % array2table(saveMat,"VariableNames",variable_names);

                clear saveMat ro rm exportMat
            end

            save(app.Load_data_Button_simulate.UserData.fit_data_file{1,1},'fit_data');
        end

        % Value changed function: graphChoice
        function graphCoice_changed(app, event)
            check_temp_data = 0;

            if(strcmp(app.CompareSwitch.Value,'On'))
                nSamples = 2;
            else
                nSamples = 1;
            end

            for iSample = 1:nSamples
                if(~isempty(app.Load_data_Button_simulate.UserData.temp_data{iSample,1}))
                    check_temp_data = 1;
                    exportMat = app.Load_data_Button_simulate.UserData.temp_data{iSample,1};
                    
                    if(strcmp(app.graphChoice.Value,'Pressure and force'))
                        plot(app.axes1_simulate_tab,exportMat.('Inner diameter [mm]'),exportMat.('Pressure [mmHg]'))
                        xlabel(app.axes1_simulate_tab,'Inner diameter [mm]','FontSize',16);
                        ylabel(app.axes1_simulate_tab,'Pressure [mmHg]','FontSize',16);
                        axis(app.axes1_simulate_tab,[0.95*min(exportMat.('Inner diameter [mm]')) 1.05*max(exportMat.('Inner diameter [mm]')),0 1.05*max(exportMat.('Pressure [mmHg]'))])
        
                        plot(app.axes2_simulate_tab,exportMat.('Pressure [mmHg]'),exportMat.("Transducer force [g]"))
                        xlabel(app.axes2_simulate_tab,'Pressure [mmHg]','FontSize',16);
                        ylabel(app.axes2_simulate_tab,'Axial force [g]','FontSize',16);
                        axis(app.axes2_simulate_tab,[0 max(exportMat.('Pressure [mmHg]')),0 2*max(exportMat.("Transducer force [g]"))])
                    else
                        plot(app.axes1_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Circ. stress [MPa]"))
                        xlabel(app.axes1_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                        ylabel(app.axes1_simulate_tab,'Circumferential stress [MPa]','FontSize',16);
                        axis(app.axes1_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Circ. stress [MPa]"))])
        
                        plot(app.axes2_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Axial stress [MPa]"))
                        xlabel(app.axes2_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                        ylabel(app.axes2_simulate_tab,'Axial stress [MPa]','FontSize',16);
                        axis(app.axes2_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Axial stress [MPa]"))])
                    end
        
                    if(strcmp(app.waveform_type_menu.Value,'Pressure sweep'))
                        test_type = 'PS,';
                    elseif(strcmp(app.waveform_type_menu.Value,'Sine wave'))
                        test_type = 'Sine';
                    elseif(strcmp(app.waveform_type_menu.Value,'Triangular waveform'))
                        test_type = 'Triangular';
                    end
        
                    data_label = [app.DBP_sim.Value '-' app.SBP_sim.Value 'mmHg,' app.Hz_value.Value 'Hz,' app.Axial_stretch_sim.Value '%\lambda_z'];
                    legend_list{1,1} = [test_type data_label];
                end
                
                if(isfield(app.Load_data_Button_simulate.UserData,'test_list'))
                    for iTest = 1:length(app.Load_data_Button_simulate.UserData.test_list)
                        test_name = app.Load_data_Button_simulate.UserData.test_list{iTest,1};
                        exportMat = app.Load_data_Button_simulate.UserData.userSimulations.(test_name);
    
                        if(strcmp(app.graphChoice.Value,'Pressure and force'))
                            hold (app.axes1_simulate_tab,'on');
                            plot(app.axes1_simulate_tab,exportMat.('Inner diameter [mm]'),exportMat.('Pressure [mmHg]'))
                            xlabel(app.axes1_simulate_tab,'Inner diameter [mm]','FontSize',16);
                            ylabel(app.axes1_simulate_tab,'Pressure [mmHg]','FontSize',16);
                            axis(app.axes1_simulate_tab,[0.95*min(exportMat.('Inner diameter [mm]')) 1.05*max(exportMat.('Inner diameter [mm]')),0 1.05*max(exportMat.('Pressure [mmHg]'))])
                                    
                            hold (app.axes2_simulate_tab,'on');
                            plot(app.axes2_simulate_tab,exportMat.('Pressure [mmHg]'),exportMat.("Transducer force [g]"))
                            xlabel(app.axes2_simulate_tab,'Pressure [mmHg]','FontSize',16);
                            ylabel(app.axes2_simulate_tab,'Axial force [g]','FontSize',16);
                            axis(app.axes2_simulate_tab,[0 max(exportMat.('Pressure [mmHg]')),0 2*max(exportMat.("Transducer force [g]"))])
                        else
                            hold (app.axes1_simulate_tab,'on');
                            plot(app.axes1_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Circ. stress [MPa]"))
                            xlabel(app.axes1_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                            ylabel(app.axes1_simulate_tab,'Circumferential stress [MPa]','FontSize',16);
                            axis(app.axes1_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Circ. stress [MPa]"))])
    
                            hold (app.axes2_simulate_tab,'on');
                            plot(app.axes2_simulate_tab,exportMat.("Circ. stretch [-]"),exportMat.("Axial stress [MPa]"))
                            xlabel(app.axes2_simulate_tab,'Circumferential stretch [-]','FontSize',16);
                            ylabel(app.axes2_simulate_tab,'Axial stress [MPa]','FontSize',16);
                            axis(app.axes2_simulate_tab,[0.95*min(exportMat.("Circ. stretch [-]")) 1.05*max(exportMat.("Circ. stretch [-]")),0 1.05*max(exportMat.("Axial stress [MPa]"))])
                        end
                        
                        locator = [];
                        for iWord = 1:length(test_name)
                            if(strcmp(test_name(iWord),'_'))
                                locator = cat(2,locator,iWord);
                            end
                        end
    
                        if(strcmp(test_name(1:locator(1)-1),'Pressure sweep'))
                            test_type = 'PS,';
                        elseif(strcmp(test_name(1:locator(1)-1),'Sine wave'))
                            test_type = 'Sine';
                        elseif(strcmp(test_name(1:locator(1)-1),'Triangular waveform'))
                            test_type = 'Triangular';
                        end
                        data_label = [test_name(locator(end-4)+2:locator(end-3)-1) '-' test_name(locator(end-3)+1:locator(end-2)-1) 'mmHg,' test_name(locator(end-2)+2:locator(end-1)-1) '.' test_name(locator(end-1)+1:locator(end)-1) 'Hz,' test_name(locator(end)+3:end) '%\lambda_z'];
                        legend_list{check_temp_data+iTest,1} = [test_type data_label];
                    end
                end 
                
                if(strcmp(app.CompareSwitch.Value,'Off'))
                    legend(app.axes1_simulate_tab,legend_list,'Location','northwest','FontSize',14)
                else
                    legend_text = strings(2,1);
                    for i = 1:2
                        text_temp = app.Load_data_Button_simulate.UserData.fit_data_file{i,1};
                        locator = find(text_temp == '/');
                        text_temp = text_temp(locator(end)+1:end-4);
                        locator = find(text_temp == '_');
                        text_temp(locator(3:end)) = ' ';
                        legend_text{i,1} = text_temp(locator(2)+1:end);
                    end
                    legend(app.axes1_simulate_tab,legend_text,'Location','northwest','FontSize',14);
                    hold (app.axes1_simulate_tab,'on');
                    hold (app.axes2_simulate_tab,'on');
                end      
            end
            hold (app.axes1_simulate_tab,'off');
            hold (app.axes2_simulate_tab,'off');
        end

        % Value changed function: CompareSwitch
        function CompareSwitchValueChanged(app, event)
            if(isfield(app.Load_data_Button_simulate.UserData, 'fit_data_file'))
                if(~isempty(app.Load_data_Button_simulate.UserData.fit_data_file))
                    appErrorDialogBox(app,'on','on','off','WARNING: You may lose the current simulations',...
                        'This action will delete all plotted simulations which have not been previously saved. Are you sure you want to proceed? If you wish to save these data, click "No" and then "Save" before re-swithcing the "Compare function".');
                end
            end
            
            if(app.Yes_button_error.Value == 1)
                if(strcmp(app.CompareSwitch.Value,'On'))
                    ResetButtonPushed(app, event);
                    app.Load_data_Button_simulate.UserData.fit_data_file = {};

                    nSamples = 2;
                    app.SaveButton.Enable = 'Off';
                    app.HoldsimulationButton.Enable = 'Off';
                else
                    ResetButtonPushed(app, event);

                    nSamples = 1;
                    app.SaveButton.Enable = 'On';
                    app.HoldsimulationButton.Enable = 'On';
                end     
            else
                if(strcmp(app.CompareSwitch.Value,'On'))
                    app.CompareSwitch.Value = 'Off';
                else
                    app.CompareSwitch.Value = 'On';
                end
            end
%             app.UIFigure.WindowState = 'maximized';app.UIFigure.WindowState = 'maximized';app.UIFigure.WindowState = 'maximized';app.UIFigure.WindowState = 'maximized';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'BioMechAnalysis';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.text10_2 = uilabel(app.figure1);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'Circumferential';
            app.text11_2 = uilabel(app.figure1);
            app.text11_2.Tag = 'text11';
            app.text11_2.Text = 'Axial';
            app.circ_el_LB = uilabel(app.figure1);
            app.circ_el_LB.Tag = 'Ctttt_value';
            app.circ_el_LB.Text = '....';
            app.axial_el_LB = uilabel(app.figure1);
            app.axial_el_LB.Tag = 'Czzzz_value';
            app.axial_el_LB.Text = '....';
            app.text12_2 = uilabel(app.figure1);
            app.text12_2.Tag = 'text12';
            app.text12_2.Text = '%';
            app.text13_2 = uilabel(app.figure1);
            app.text13_2.Tag = 'text13';
            app.text13_2.Text = '%';
            app.text21_2 = uilabel(app.figure1);
            app.text21_2.Tag = 'text21';
            app.text21_2.Text = 'Elastin''s load bearing';
            app.text10_3 = uilabel(app.figure1);
            app.text10_3.Tag = 'text10';
            app.text10_3.Text = 'Circumferential';
            app.text11_3 = uilabel(app.figure1);
            app.text11_3.Tag = 'text11';
            app.text11_3.Text = 'Axial';
            app.circ_coll_LB = uilabel(app.figure1);
            app.circ_coll_LB.Tag = 'Ctttt_value';
            app.circ_coll_LB.Text = '....';
            app.axial_coll_LB = uilabel(app.figure1);
            app.axial_coll_LB.Tag = 'Czzzz_value';
            app.axial_coll_LB.Text = '....';
            app.text12_3 = uilabel(app.figure1);
            app.text12_3.Tag = 'text12';
            app.text12_3.Text = '%';
            app.text13_3 = uilabel(app.figure1);
            app.text13_3.Tag = 'text13';
            app.text13_3.Text = '%';
            app.text21_3 = uilabel(app.figure1);
            app.text21_3.Tag = 'text21';
            app.text21_3.Text = 'Collagen''s load bearing';
            app.pressure_slider = uislider(app.figure1);
            app.pressure_slider.Limits = [0 200];
            app.pressure_slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.pressure_slider.ValueChangedFcn = createCallbackFcn(app, @pressureSliderModified, true);
            app.pressure_slider.MinorTicks = [5 15 25 35 45 55 65 75 85 95 105 115 125 135 145 155 165 175 185 195];
            app.pressure_slider.Tag = 'pressure_slider';
            app.static_pressure = uilabel(app.figure1);
            app.static_pressure.Tag = 'static_pressure';
            app.static_pressure.Text = 'Pressure';
            app.pressure_value = uieditfield(app.figure1, 'text');
            app.pressure_value.ValueChangedFcn = createCallbackFcn(app, @pressureValueModified, true);
            app.pressure_value.Tag = 'pressure_value';
            app.pressure_value.Value = '....';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Circumferential';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Axial';
            app.sigmatt_value = uilabel(app.figure1);
            app.sigmatt_value.Tag = 'sigmatt_value';
            app.sigmatt_value.Text = '....';
            app.sigmazz_value = uilabel(app.figure1);
            app.sigmazz_value.Tag = 'sigmazz_value';
            app.sigmazz_value.Text = '....';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'MPa';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'MPa';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Circumferential';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Axial';
            app.Ctttt_value = uilabel(app.figure1);
            app.Ctttt_value.Tag = 'Ctttt_value';
            app.Ctttt_value.Text = '....';
            app.Czzzz_value = uilabel(app.figure1);
            app.Czzzz_value.Tag = 'Czzzz_value';
            app.Czzzz_value.Text = '....';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'MPa';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'MPa';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'PWV';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Distensibility';
            app.PWV_value = uilabel(app.figure1);
            app.PWV_value.Tag = 'PWV_value';
            app.PWV_value.Text = '....';
            app.distensibility_value = uilabel(app.figure1);
            app.distensibility_value.Tag = 'distensibility_value';
            app.distensibility_value.Text = '....';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'm/s';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = '1/MPa';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'mmHg';
            app.calculate = uibutton(app.figure1, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateBiomechanicalVariables, true);
            app.calculate.Tag = 'calculate';
            app.calculate.Text = 'Calculate';
            app.load_data_button = uibutton(app.figure1, 'push');
            app.load_data_button.ButtonPushedFcn = createCallbackFcn(app, @loadDataDataAnalysisTab, true);
            app.load_data_button.Tag = 'load_data_button';
            app.load_data_button.Text = 'Load data';
            app.graph_choice = uidropdown(app.figure1);
            app.graph_choice.Items = {'Pressure and force', 'Stresses', 'Load bearing (P&F)', 'Load bearing (stresses)'};
            app.graph_choice.ValueChangedFcn = createCallbackFcn(app, @graphChoiceDataAnalysisTab, true);
            app.graph_choice.Tag = 'graph_choice';
            app.graph_choice.Value = 'Pressure and force';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Cauchy stress';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Stiffness';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'SBP';
            app.SBP_value = uieditfield(app.figure1, 'text');
            app.SBP_value.ValueChangedFcn = createCallbackFcn(app, @graphChoiceDataAnalysisTab, true);
            app.SBP_value.Tag = 'SBP_value';
            app.SBP_value.Value = '....';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'mmHg';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'DBP';
            app.DBP_value = uieditfield(app.figure1, 'text');
            app.DBP_value.ValueChangedFcn = createCallbackFcn(app, @graphChoiceDataAnalysisTab, true);
            app.DBP_value.Tag = 'DBP_value';
            app.DBP_value.Value = '....';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = 'mmHg';
            app.Reset = uibutton(app.figure1, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @resetDataAnalysisTab, true);
            app.Reset.Tag = 'Reset';
            app.Reset.Text = 'Reset';
            app.save = uibutton(app.figure1, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveBiomechanicalVariables, true);
            app.save.Tag = 'save';
            app.save.Text = 'Save';
            app.clear = uibutton(app.figure1, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearBiomechanicalvariables, true);
            app.clear.Tag = 'clear';
            app.clear.Text = 'Clear var';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = {'DynamX App (V2.1)'; 'Department of Biomedical Engineering'; 'CARIM School for Cardiovascular Diseases'; 'Maastricht University'};
            app.Measurements_tab = uipanel(app.figure1);
            app.Measurements_tab.Visible = 'off';
            app.Measurements_tab.Tag = 'Measurements_tab';
            app.axes1_mod_tab = uiaxes(app.Measurements_tab);
            app.axes1_mod_tab.Tag = 'axes1_mod_tab';
            app.axes2_mod_tab = uiaxes(app.Measurements_tab);
            app.axes2_mod_tab.Tag = 'axes2_mod_tab';
            app.Load_data_mod_tab = uibutton(app.Measurements_tab, 'push');
            app.Load_data_mod_tab.ButtonPushedFcn = createCallbackFcn(app, @loadDataModellingTab, true);
            app.Load_data_mod_tab.Tag = 'Load_data_mod_tab';
            app.Load_data_mod_tab.Text = 'Load data';
            app.text27 = uilabel(app.Measurements_tab);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Current status:';
            app.status_txt = uilabel(app.Measurements_tab);
            app.status_txt.Tag = 'status_txt';
            app.status_txt.Text = 'Waiting for data input...';
            app.graph_choice_mod_tab = uidropdown(app.Measurements_tab);
            app.graph_choice_mod_tab.Items = {'Pressure and force', 'Stresses'};
            app.graph_choice_mod_tab.ValueChangedFcn = createCallbackFcn(app, @graphChoiceModellingTab, true);
            app.graph_choice_mod_tab.Tag = 'graph_choice_mod_tab';
            app.graph_choice_mod_tab.Value = 'Pressure and force';
            app.text_not_cahnged = uilabel(app.Measurements_tab);
            app.text_not_cahnged.Tag = 'text_not_cahnged';
            app.text_not_cahnged.Text = 'R^2 (static):';
            app.static_R2_value = uilabel(app.Measurements_tab);
            app.static_R2_value.Tag = 'static_R2_value';
            app.static_R2_value.Text = '....';
            app.text32 = uilabel(app.Measurements_tab);
            app.text32.Tag = 'text32';
            app.text32.Text = 'R^2 (dynamic):';
            app.dynamic_R2_value = uilabel(app.Measurements_tab);
            app.dynamic_R2_value.Tag = 'dynamic_R2_value';
            app.dynamic_R2_value.Text = '....';
            app.text34 = uilabel(app.Measurements_tab);
            app.text34.Tag = 'text34';
            app.text34.Text = 'In vivo axial stretch (experimental estimation):';
            app.in_vivo_axial_stretch_exp = uilabel(app.Measurements_tab);
            app.in_vivo_axial_stretch_exp.Tag = 'in_vivo_axial_stretch_exp';
            app.in_vivo_axial_stretch_exp.Text = '....';
            app.text36 = uilabel(app.Measurements_tab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'In vivo axial stretch (Intersection point):';
            app.in_vivo_axial_stretch_mod = uilabel(app.Measurements_tab);
            app.in_vivo_axial_stretch_mod.Tag = 'in_vivo_axial_stretch_mod';
            app.in_vivo_axial_stretch_mod.Text = '....';
            app.random_text2 = uilabel(app.Measurements_tab);
            app.random_text2.Tag = 'random_text2';
            app.random_text2.Text = 'Stain energy function:';
            app.SEF = uilabel(app.Measurements_tab);
            app.SEF.Tag = 'SEF';
            app.SEF.Text = '....';
            app.random_text_44 = uilabel(app.Measurements_tab);
            app.random_text_44.Tag = 'random_text_44';
            app.random_text_44.Text = 'Memory function:';
            app.memory_fun = uilabel(app.Measurements_tab);
            app.memory_fun.Tag = 'memory_fun';
            app.memory_fun.Text = '....';
            app.text42 = uilabel(app.Measurements_tab);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Number of parameters:';
            app.N_param_static = uilabel(app.Measurements_tab);
            app.N_param_static.Tag = 'N_param_static';
            app.N_param_static.Text = '....';
            app.text44 = uilabel(app.Measurements_tab);
            app.text44.Tag = 'text44';
            app.text44.Text = 'of which';
            app.N_param_constrained = uilabel(app.Measurements_tab);
            app.N_param_constrained.Tag = 'N_param_constrained';
            app.N_param_constrained.Text = '....';
            app.text46 = uilabel(app.Measurements_tab);
            app.text46.Tag = 'text46';
            app.text46.Text = 'constrained.';
            app.text47 = uilabel(app.Measurements_tab);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Number of parameters:';
            app.N_param_dynamic = uilabel(app.Measurements_tab);
            app.N_param_dynamic.Tag = 'N_param_dynamic';
            app.N_param_dynamic.Text = '....';
            app.text49 = uilabel(app.Measurements_tab);
            app.text49.Tag = 'text49';
            app.text49.Text = 'Reference configuration:';
            app.ref_conf = uilabel(app.Measurements_tab);
            app.ref_conf.Tag = 'ref_conf';
            app.ref_conf.Text = '....';
            app.graph_choice_mod_tab_2 = uidropdown(app.Measurements_tab);
            app.graph_choice_mod_tab_2.Items = {'Pressure sweeps (passive)', 'Pressure sweeps (active)', 'Axial length sweeps', 'Dynamic loops (passive)', 'Dynamic loops (active)'};
            app.graph_choice_mod_tab_2.ValueChangedFcn = createCallbackFcn(app, @graphChoiceModellingTab, true);
            app.graph_choice_mod_tab_2.Tag = 'graph_choice_mod_tab_2';
            app.graph_choice_mod_tab_2.Value = 'Pressure sweeps (passive)';
            app.dat_proc_tab_button = uibutton(app.figure1, 'push');
            app.dat_proc_tab_button.ButtonPushedFcn = createCallbackFcn(app, @dataProcessingTab, true);
            app.dat_proc_tab_button.Tag = 'dat_proc_tab_button';
            app.dat_proc_tab_button.Text = 'Data processing';
            app.dat_proc_tab = uipanel(app.figure1);
            app.dat_proc_tab.Tag = 'dat_proc_tab';
            app.axes1_dat_proc_tab = uiaxes(app.dat_proc_tab);
            app.axes1_dat_proc_tab.Tag = 'axes1_dat_proc_tab';
            app.axes2_dat_proc_tab = uiaxes(app.dat_proc_tab);
            app.axes2_dat_proc_tab.Tag = 'axes2_dat_proc_tab';
            app.axes3_dat_proc_tab = uiaxes(app.dat_proc_tab);
            app.axes3_dat_proc_tab.Tag = 'axes3_dat_proc_tab';
            app.axes4_dat_proc_tab = uiaxes(app.dat_proc_tab);
            app.axes4_dat_proc_tab.Tag = 'axes4_dat_proc_tab';
            app.load_data_dat_proc = uibutton(app.dat_proc_tab, 'push');
            app.load_data_dat_proc.ButtonPushedFcn = createCallbackFcn(app, @loadDataDataProcessingTab, true);
            app.load_data_dat_proc.Tag = 'load_data_dat_proc';
            app.load_data_dat_proc.Text = 'Load data';
            app.dynamic_menu = uidropdown(app.dat_proc_tab);
            app.dynamic_menu.Items = {'Off', 'On'};
            app.dynamic_menu.Tag = 'dynamic_menu';
            app.dynamic_menu.Value = 'Off';
            app.text51 = uilabel(app.dat_proc_tab);
            app.text51.Tag = 'text51';
            app.text51.Text = 'External radius:';
            app.text52 = uilabel(app.dat_proc_tab);
            app.text52.Tag = 'text52';
            app.text52.Text = 'Internal radius:';
            app.unloaded_Ro = uieditfield(app.dat_proc_tab, 'text');
            app.unloaded_Ro.Tag = 'unloaded_Ro';
            app.unloaded_Ro.Value = '....';
            app.unloaded_Ri = uieditfield(app.dat_proc_tab, 'text');
            app.unloaded_Ri.Tag = 'unloaded_Ri';
            app.unloaded_Ri.Value = '....';
            app.text53 = uilabel(app.dat_proc_tab);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Axial length:';
            app.unloaded_lz = uieditfield(app.dat_proc_tab, 'text');
            app.unloaded_lz.Tag = 'unloaded_lz';
            app.unloaded_lz.Value = '....';
            app.text54 = uilabel(app.dat_proc_tab);
            app.text54.Tag = 'text54';
            app.text54.Text = 'um';
            app.text55 = uilabel(app.dat_proc_tab);
            app.text55.Tag = 'text55';
            app.text55.Text = 'um';
            app.text56 = uilabel(app.dat_proc_tab);
            app.text56.Tag = 'text56';
            app.text56.Text = 'mm';
            app.text57 = uilabel(app.dat_proc_tab);
            app.text57.Tag = 'text57';
            app.text57.Text = 'Unloaded dimensions:';
            app.text58 = uilabel(app.dat_proc_tab);
            app.text58.Tag = 'text58';
            app.text58.Text = 'Mouse ID:';
            app.mouse_ID_text = uieditfield(app.dat_proc_tab, 'text');
            app.mouse_ID_text.Tag = 'mouse_ID_text';
            app.mouse_ID_text.Value = '....';
            app.text59 = uilabel(app.dat_proc_tab);
            app.text59.Tag = 'text59';
            app.text59.Text = 'Dynamic data:';
            app.static_dyna_menu_data_proc = uidropdown(app.dat_proc_tab);
            app.static_dyna_menu_data_proc.Items = {'Static', 'Dynamic (physiological)', 'Dynamic (sine)'};
            app.static_dyna_menu_data_proc.ValueChangedFcn = createCallbackFcn(app, @graphChoiceDataProcessing, true);
            app.static_dyna_menu_data_proc.Tag = 'static_dyna_menu_data_proc';
            app.static_dyna_menu_data_proc.Value = 'Static';
            app.text60 = uilabel(app.dat_proc_tab);
            app.text60.Tag = 'text60';
            app.text60.Text = 'Testing protocol:';
            app.protocol_menu = uidropdown(app.dat_proc_tab);
            app.protocol_menu.Items = {'Old', 'New', 'Custom'};
            app.protocol_menu.Tag = 'protocol_menu';
            app.protocol_menu.Value = 'Old';
            app.axial_stretch_menu = uidropdown(app.dat_proc_tab);
            app.axial_stretch_menu.Items = {'Raw data', 'In vivo axial stretch'};
            app.axial_stretch_menu.ValueChangedFcn = createCallbackFcn(app, @inVivoAxialStretchGraphChoice, true);
            app.axial_stretch_menu.Tag = 'static_dyna_menu_data_proc';
            app.axial_stretch_menu.Value = 'Raw data';
            app.LoadeddataLabel = uilabel(app.dat_proc_tab);
            app.LoadeddataLabel.Text = 'Loaded data [%]';
            app.data_proc_dial = uigauge(app.dat_proc_tab, 'semicircular');
            app.contraction_menu = uidropdown(app.dat_proc_tab);
            app.contraction_menu.Items = {'Off', 'On'};
            app.contraction_menu.Tag = 'dynamic_menu';
            app.contraction_menu.Value = 'Off';
            app.text59_2 = uilabel(app.dat_proc_tab);
            app.text59_2.Tag = 'text59';
            app.text59_2.Text = 'Contraction data:';
            app.Const_mod_tab_button = uibutton(app.figure1, 'push');
            app.Const_mod_tab_button.ButtonPushedFcn = createCallbackFcn(app, @constitutiveModellingTab, true);
            app.Const_mod_tab_button.Tag = 'Const_mod_tab_button';
            app.Const_mod_tab_button.Text = 'Constitutive modelling';
            app.data_analysis_tab_button = uibutton(app.figure1, 'push');
            app.data_analysis_tab_button.ButtonPushedFcn = createCallbackFcn(app, @dataAnalysisTab, true);
            app.data_analysis_tab_button.Tag = 'data_analysis_tab_button';
            app.data_analysis_tab_button.Text = 'Data analysis';
            app.text_mmHg_slider = uilabel(app.figure1);
            app.text_mmHg_slider.Tag = 'text_mmHg_slider';
            app.text_mmHg_slider.Visible = 'off';
            app.text_mmHg_slider.Text = 'mmHg';
            app.simulate_button = uibutton(app.figure1, 'push');
            app.simulate_button.ButtonPushedFcn = createCallbackFcn(app, @simulateTab, true);
            app.simulate_button.Tag = 'data_analysis_tab_button';
            app.simulate_button.Text = 'Simulate';
            app.simulate_tab = uipanel(app.figure1);
            app.simulate_tab.Visible = 'off';
            app.axes1_simulate_tab = uiaxes(app.simulate_tab);
            app.axes1_simulate_tab.Tag = 'axes1_mod_tab';
            app.axes2_simulate_tab = uiaxes(app.simulate_tab);
            app.axes2_simulate_tab.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes2_simulate_tab.Tag = 'axes2_mod_tab';
            app.axes3_simulate_tab = uiaxes(app.simulate_tab);
            xlabel(app.axes3_simulate_tab, 'Time [ms]')
            ylabel(app.axes3_simulate_tab, 'Pressure [mmHg]')
            app.axes3_simulate_tab.XTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'; '700'; '800'; '900'; '1000'};
            app.axes3_simulate_tab.YTickLabel = {'0'; '50'; '100'; '150'; '200'};
            app.axes3_simulate_tab.Tag = 'axes1_mod_tab';
            app.HzLabel = uilabel(app.simulate_tab);
            app.HzLabel.Text = 'Hz';
            app.Hz_value = uieditfield(app.simulate_tab, 'text');
            app.Hz_value.ValueChangedFcn = createCallbackFcn(app, @Hz_value_Callback, true);
            app.Hz_value.Value = '0';
            app.ofinvivoaxialstretchLabel = uilabel(app.simulate_tab);
            app.ofinvivoaxialstretchLabel.Text = '% of in vivo axial stretch';
            app.PressurerangeEditFieldLabel = uilabel(app.simulate_tab);
            app.PressurerangeEditFieldLabel.Text = 'Pressure range:';
            app.SBP_sim = uieditfield(app.simulate_tab, 'text');
            app.SBP_sim.ValueChangedFcn = createCallbackFcn(app, @SBP_sim_Callback, true);
            app.SBP_sim.Value = '....';
            app.EditFieldLabel = uilabel(app.simulate_tab);
            app.EditFieldLabel.Text = '-';
            app.DBP_sim = uieditfield(app.simulate_tab, 'text');
            app.DBP_sim.ValueChangedFcn = createCallbackFcn(app, @DBP_sim_Callback, true);
            app.DBP_sim.Value = '....';
            app.mmHgLabel = uilabel(app.simulate_tab);
            app.mmHgLabel.Text = 'mmHg';
            app.LoadingfrequencyLabel = uilabel(app.simulate_tab);
            app.LoadingfrequencyLabel.Text = 'Loading frequency';
            app.Hz_knob = uiknob(app.simulate_tab, 'continuous');
            app.Hz_knob.Limits = [-6 6];
            app.Hz_knob.MajorTicks = [-6 -4 -2 0 2 4 6];
            app.Hz_knob.MajorTickLabels = {'0.000001', '0.0001', '0.01', '1', '100', '10000', '1000000'};
            app.Hz_knob.ValueChangedFcn = createCallbackFcn(app, @Hz_knob_Callback, true);
            app.Hz_knob.MinorTicks = [-6 -5.6 -5.2 -4.8 -4.4 -4 -3.6 -3.2 -2.8 -2.4 -2 -1.6 -1.2 -0.8 -0.4 0 0.4 0.8 1.2 1.6 2 2.4 2.8 3.2 3.6 4 4.4 4.8 5.2 5.6 6];
            app.AxialstretchEditField_2Label = uilabel(app.simulate_tab);
            app.AxialstretchEditField_2Label.Text = 'Axial stretch:';
            app.Axial_stretch_sim = uieditfield(app.simulate_tab, 'text');
            app.Axial_stretch_sim.Value = '100';
            app.Label = uilabel(app.simulate_tab);
            app.Label.Text = '';
            app.WaveformtypeDropDownLabel = uilabel(app.simulate_tab);
            app.WaveformtypeDropDownLabel.Text = 'Waveform type';
            app.waveform_type_menu = uidropdown(app.simulate_tab);
            app.waveform_type_menu.Items = {'Pressure sweep', 'Triangular waveform', 'Sine wave', 'Physiological waveform'};
            app.waveform_type_menu.ValueChangedFcn = createCallbackFcn(app, @waveform_type_Callback, true);
            app.waveform_type_menu.Value = 'Pressure sweep';
            app.RunsimulationButton = uibutton(app.simulate_tab, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @run_simulation_pressed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.HoldsimulationButton = uibutton(app.simulate_tab, 'push');
            app.HoldsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @hold_simulation_button_pushed, true);
            app.HoldsimulationButton.Text = 'Hold simulation';
            app.ResetButton = uibutton(app.simulate_tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Load_data_Button_simulate = uibutton(app.simulate_tab, 'push');
            app.Load_data_Button_simulate.ButtonPushedFcn = createCallbackFcn(app, @load_data_button_simulate, true);
            app.Load_data_Button_simulate.Text = 'Load data';
            app.SaveButton = uibutton(app.simulate_tab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.graphChoice = uidropdown(app.simulate_tab);
            app.graphChoice.Items = {'Pressure and force', 'Stresses'};
            app.graphChoice.ValueChangedFcn = createCallbackFcn(app, @graphCoice_changed, true);
            app.graphChoice.Value = 'Pressure and force';
            app.CompareSwitchLabel = uilabel(app.simulate_tab);
            app.CompareSwitchLabel.Text = 'Compare';
            app.CompareSwitch = uiswitch(app.simulate_tab, 'slider');
            app.CompareSwitch.ValueChangedFcn = createCallbackFcn(app, @CompareSwitchValueChanged, true);
            app.VSMCcontractionSwitchLabel = uilabel(app.simulate_tab);
            app.VSMCcontractionSwitchLabel.Text = 'VSMC contraction';
            app.VSMCcontractionSwitch = uiswitch(app.simulate_tab, 'slider');
            app.mod_settings_tab = uipanel(app.figure1);
            app.mod_settings_tab.Title = 'Modelling settings';
            app.mod_settings_tab.Visible = 'off';
            app.StrainenergyfunctionLabel = uilabel(app.mod_settings_tab);
            app.StrainenergyfunctionLabel.Text = 'Passive strain energy function:';
            app.SEF_menu = uidropdown(app.mod_settings_tab);
            app.SEF_menu.Items = {'4-fibre families', '2-fibre families', '2-fibre families with dispersion', 'Zulliger''s SEF', 'Zulliger''s SEF (2-fibre)', '4-fibre families (not neo-Hookean)', 'Bilayered model', 'UFD model (not neo-Hookean)'};
            app.SEF_menu.Value = '4-fibre families';
            app.MemoryfunctionLabel = uilabel(app.mod_settings_tab);
            app.MemoryfunctionLabel.Text = 'Memory function:';
            app.memory_fun_menu = uidropdown(app.mod_settings_tab);
            app.memory_fun_menu.Items = {'None', 'Fung', 'Prony series'};
            app.memory_fun_menu.Value = 'None';
            app.MemoryfunctionLabel_2 = uilabel(app.mod_settings_tab);
            app.MemoryfunctionLabel_2.Text = 'Input data:';
            app.input_data_menu = uidropdown(app.mod_settings_tab);
            app.input_data_menu.Items = {'All (pressure and axial force sweeps)', 'Pressure sweeps', 'Pressure sweep at in vivo length'};
            app.input_data_menu.Value = 'All (pressure and axial force sweeps)';
            app.ReferenceconfigurationLabel = uilabel(app.mod_settings_tab);
            app.ReferenceconfigurationLabel.Text = 'Reference configuration:';
            app.ref_conf_menu = uidropdown(app.mod_settings_tab);
            app.ref_conf_menu.Items = {'Unloaded vessel', 'In vivo (100 mmHg and in vivo axial length)'};
            app.ref_conf_menu.Value = 'Unloaded vessel';
            app.EnterButton = uibutton(app.mod_settings_tab, 'state');
            app.EnterButton.Text = 'Enter';
            app.MemoryfunctionLabel_3 = uilabel(app.mod_settings_tab);
            app.MemoryfunctionLabel_3.Text = 'Active strain energy function';
            app.active_model = uidropdown(app.mod_settings_tab);
            app.active_model.Items = {'None', 'Constant 2PK stress', 'Constant 1PK stress', 'Constant Cauchy stress', 'Rachev 1999', 'Zulliger 2004', 'Franchini 2022', 'Gaussian model'};
            app.active_model.Value = 'None';
            app.model_param_tab = uipanel(app.figure1);
            app.model_param_tab.Title = 'Model parameters';
            app.model_param_tab.Visible = 'off';
            app.OKButton = uibutton(app.model_param_tab, 'state');
            app.OKButton.Text = 'OK';
            app.Parameter1Label = uilabel(app.model_param_tab);
            app.Parameter1Label.Text = 'Parameter 1';
            app.param_1 = uieditfield(app.model_param_tab, 'text');
            app.Parameter2Label = uilabel(app.model_param_tab);
            app.Parameter2Label.Text = {'Parameter 2'; ''};
            app.param_2 = uieditfield(app.model_param_tab, 'text');
            app.Parameter4Label = uilabel(app.model_param_tab);
            app.Parameter4Label.Text = 'Parameter 4';
            app.param_4 = uieditfield(app.model_param_tab, 'text');
            app.Parameter5Label = uilabel(app.model_param_tab);
            app.Parameter5Label.Text = 'Parameter 5';
            app.param_5 = uieditfield(app.model_param_tab, 'text');
            app.Parameter6Label = uilabel(app.model_param_tab);
            app.Parameter6Label.Text = 'Parameter 6';
            app.param_6 = uieditfield(app.model_param_tab, 'text');
            app.Parameter12Label = uilabel(app.model_param_tab);
            app.Parameter12Label.Text = 'Parameter 12';
            app.param_12 = uieditfield(app.model_param_tab, 'text');
            app.Parameter13Label = uilabel(app.model_param_tab);
            app.Parameter13Label.Text = 'Parameter 13';
            app.param_13 = uieditfield(app.model_param_tab, 'text');
            app.Parameter7Label = uilabel(app.model_param_tab);
            app.Parameter7Label.Text = 'Parameter 7';
            app.param_7 = uieditfield(app.model_param_tab, 'text');
            app.Parameter8Label = uilabel(app.model_param_tab);
            app.Parameter8Label.Text = 'Parameter 8';
            app.param_8 = uieditfield(app.model_param_tab, 'text');
            app.Parameter9Label = uilabel(app.model_param_tab);
            app.Parameter9Label.Text = 'Parameter 9';
            app.param_9 = uieditfield(app.model_param_tab, 'text');
            app.Parameter10Label = uilabel(app.model_param_tab);
            app.Parameter10Label.Text = 'Parameter 10';
            app.param_10 = uieditfield(app.model_param_tab, 'text');
            app.Parameter11Label = uilabel(app.model_param_tab);
            app.Parameter11Label.Text = 'Parameter 11';
            app.param_11 = uieditfield(app.model_param_tab, 'text');
            app.Parameter3Label = uilabel(app.model_param_tab);
            app.Parameter3Label.Text = 'Parameter 3';
            app.param_3 = uieditfield(app.model_param_tab, 'text');
            app.Parameter14Label = uilabel(app.model_param_tab);
            app.Parameter14Label.Text = 'Parameter 14';
            app.param_14 = uieditfield(app.model_param_tab, 'text');
            app.error_box_tab = uipanel(app.figure1);
            app.error_box_tab.Title = 'ERROR/WARNING/MESSAGE';
            app.error_box_tab.Visible = 'off';
            app.text_error_box = uitextarea(app.error_box_tab);
            app.OK_button_error = uibutton(app.error_box_tab, 'state');
            app.OK_button_error.Text = 'OK';
            app.Yes_button_error = uibutton(app.error_box_tab, 'state');
            app.Yes_button_error.Text = 'Yes';
            app.No_button_error = uibutton(app.error_box_tab, 'state');
            app.No_button_error.Text = 'No';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BioMechAnalysis_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)BioMechAnalysis_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end